classdef HoloShow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        holoShow                     matlab.ui.Figure
        range_edit                   matlab.ui.control.NumericEditField
        scanrangeEditFieldLabel      matlab.ui.control.Label
        phase_edit                   matlab.ui.control.NumericEditField
        clusterradius_slider         matlab.ui.control.Slider
        plus10_radius_pushbutton     matlab.ui.control.Button
        plus5_radius_pushbutton      matlab.ui.control.Button
        plus2_radius_pushbutton      matlab.ui.control.Button
        minus10_radius_pushbutton    matlab.ui.control.Button
        minus5_radius_pushbutton     matlab.ui.control.Button
        minus2_radius_pushbutton     matlab.ui.control.Button
        clusterradius_edit           matlab.ui.control.NumericEditField
        radiusnmEditFieldLabel       matlab.ui.control.Label
        scattRatio_edit              matlab.ui.control.NumericEditField
        logscattratioEditFieldLabel  matlab.ui.control.Label
        nm3_text                     matlab.ui.control.Label
        nmPhase_text                 matlab.ui.control.Label
        scattRatio_slider            matlab.ui.control.Slider
        command_text                 matlab.ui.control.Label
        command_edit                 matlab.ui.control.EditField
        minus1_radius_pushbutton     matlab.ui.control.Button
        plus1_radius_pushbutton      matlab.ui.control.Button
        minusphase_pushbutton        matlab.ui.control.Button
        plusphase_pushbutton         matlab.ui.control.Button
        save_checkbox                matlab.ui.control.CheckBox
        abort_pushbutton             matlab.ui.control.Button
        save_handles_pushbutton      matlab.ui.control.Button
        autofocusMethod_text         matlab.ui.control.Label
        autofocus_popupmenu          matlab.ui.control.DropDown
        uipanel2                     matlab.ui.container.Panel
        squareROI_checkbox           matlab.ui.control.CheckBox
        reset_pushbutton             matlab.ui.control.Button
        chooseCC_pushbutton          matlab.ui.control.Button
        pxLabel                      matlab.ui.control.Label
        cut_center_checkbox          matlab.ui.control.CheckBox
        cut_center_edit              matlab.ui.control.EditField
        cropTimes_text               matlab.ui.control.Label
        crop_checkbox                matlab.ui.control.CheckBox
        crop_edit                    matlab.ui.control.EditField
        colormap_buttongroup         matlab.ui.container.ButtonGroup
        r2b_radiobutton              matlab.ui.control.RadioButton
        gray_radiobutton             matlab.ui.control.RadioButton
        ihesperia_radiobutton        matlab.ui.control.RadioButton
        hsv_radiobutton              matlab.ui.control.RadioButton
        toRange_text                 matlab.ui.control.Label
        fromRange_text               matlab.ui.control.Label
        log_checkbox                 matlab.ui.control.CheckBox
        scale_checkbox               matlab.ui.control.CheckBox
        max_edit                     matlab.ui.control.EditField
        min_edit                     matlab.ui.control.EditField
        part_buttongroup             matlab.ui.container.ButtonGroup
        real_r                       matlab.ui.control.RadioButton
        imag_r                       matlab.ui.control.RadioButton
        abs_r                        matlab.ui.control.RadioButton
        angle_r                      matlab.ui.control.RadioButton
        uipanel4                     matlab.ui.container.Panel
        calcMultiscore_pushbutton    matlab.ui.control.Button
        parameter_window_pushbutton  matlab.ui.control.Button
        choose_runs_pushbutton       matlab.ui.control.Button
        find_decon_pushbutton        matlab.ui.control.Button
        center_pushbutton            matlab.ui.control.Button
        shifts_pushbutton            matlab.ui.control.Button
        wholeRun_pushbutton          matlab.ui.control.Button
        focusCC_pushbutton           matlab.ui.control.Button
        findCC_pushbutton            matlab.ui.control.Button
        uipanel3                     matlab.ui.container.Panel
        pxLabel_4                    matlab.ui.control.Label
        pxLabel_3                    matlab.ui.control.Label
        pxLabel_2                    matlab.ui.control.Label
        intensity_filter_checkbox    matlab.ui.control.CheckBox
        intensity_filter_edit        matlab.ui.control.EditField
        mDetDistance_text            matlab.ui.control.Label
        detDistance_text             matlab.ui.control.Label
        detDist_edit                 matlab.ui.control.EditField
        nmLambda_text                matlab.ui.control.Label
        lambda_text                  matlab.ui.control.Label
        wavelength_edit              matlab.ui.control.EditField
        load_mask_checkbox           matlab.ui.control.CheckBox
        cm_checkbox                  matlab.ui.control.CheckBox
        lowpass_checkbox             matlab.ui.control.CheckBox
        highpass_checkbox            matlab.ui.control.CheckBox
        highpass_edit                matlab.ui.control.EditField
        lowpass_edit                 matlab.ui.control.EditField
        decon_checkbox               matlab.ui.control.CheckBox
        knife_edge_pushbutton        matlab.ui.control.Button
        goodShot_pushbutton          matlab.ui.control.Button
        powerSpec_pushbutton         matlab.ui.control.Button
        noise_pushbutton             matlab.ui.control.Button
        SNR_pushbutton               matlab.ui.control.Button
        find_phase_pushbutton        matlab.ui.control.Button
        phase_text                   matlab.ui.control.Label
        phase_slider                 matlab.ui.control.Slider
        uipanel1                     matlab.ui.container.Panel
        experiment_text              matlab.ui.control.Label
        config_dropdown              matlab.ui.control.DropDown
        smoothMask_checkbox          matlab.ui.control.CheckBox
        applyHologramCorrection_checkbox  matlab.ui.control.CheckBox
        previous_pushbutton          matlab.ui.control.Button
        next_pushbutton              matlab.ui.control.Button
        select_pushbutton            matlab.ui.control.Button
        filenames_listbox            matlab.ui.control.ListBox
        load_pushbutton              matlab.ui.control.Button
    end


    properties (Access = public)
        handles
        colors
        config
        data
        parameter
        thisrun
        db
        links
        masking
    end


    methods (Access = private)

        function scrollHologram(app,event,scrollDirection)
            if nargin<3
                scrollDirection = 0;
            end
            if scrollDirection < 0
                if app.handles.fileIndex > 0
                    app.handles.fileIndex = app.handles.fileIndex-1;
                end
            elseif scrollDirection > 0
                if app.handles.fileIndex < app.handles.nbr_images+1
                    app.handles.fileIndex = app.handles.fileIndex+1;
                end
            else
                return
            end

            app.filenames_listbox.Value = app.handles.fileIndex;
            select_hologram(app, event);

        end

        function loadh5file(app)

            app.handles.entrylist = h5read(fullfile(app.handles.pathname, app.handles.first_file), app.handles.h5path.bunch_id);
            app.handles.nbr_images = size(app.handles.entrylist,1);

            app.thisrun.run = round(str2double(app.handles.first_file(2:5)));
            app.thisrun.nbr_images = app.handles.nbr_images;

            app.filenames_listbox.Items = cellfun(@(x) num2str(x), num2cell(app.handles.entrylist'), 'UniformOutput', false);

            app.loadDB();
            app.createDataTable();
            app.createHitscorePlot();

        end

        function calcMulti(app, ~)
            cutCenterPxR = 100;
            medianThreshold = 5;
            reconMulti = app.handles.recon;
            s = size(reconMulti);
            c=round(s/2);
            [xx,yy] = meshgrid( (1:s(2)) - c(2), (1:s(1)) - c(1) );
            reconMulti = reconMulti.*(xx.^2 + yy.^2 > cutCenterPxR.^2);
            medianVal = median(abs(reconMulti(:)), 'omitnan');
            multiMatrix = reconMulti>medianThreshold*medianVal;
            multiScore = sum(multiMatrix(:));
            app.db.MultiScore(app.handles.fileIndex) = multiScore;
            app.handles.db.uitable.Data.MultiScore(app.handles.fileIndex) = multiScore;
%             figure(4545);
%             imagesc(abs(reconMulti));
%             title(sprintf('Multi Score = %.0f', multiScore));
%             axis square;
        end
        
        function save_data_barmherzig(app, ~)
            app.handles.detPixelsize = 75e-6;
            writematrix(app.handles.hologram.corrected, fullfile('data', 'barmherzig',  sprintf('%s_data_corrected.dat', app.handles.img_id)))
            writematrix(app.handles.hologram.masked, fullfile('data', 'barmherzig',  sprintf('%s_data_masked.dat', app.handles.img_id)))
            writematrix(app.handles.mask, fullfile('data', 'barmherzig',  sprintf('%s_soft_mask.dat', app.handles.img_id)))
            writematrix(app.handles.hardmask, fullfile('data', 'barmherzig',  sprintf('%s_hard_mask.dat', app.handles.img_id)))
            writematrix(app.handles.deconvolution.Freference, fullfile('data', 'barmherzig',  sprintf('%s_Fourier_reference.dat', app.handles.img_id)))
            fprintf('saved data in %s\n', fullfile('data', 'barmherzig',  sprintf('%s_*.dat', app.handles.img_id)))
            fprintf('data: id, xmin, xmax, ymin, ymax, delta_z, R_ref, log10(scatt_ratio), wavelength, det_dist, pixel_size\n')
            fprintf('data: %s, %.0f, %.0f, %.0f, %.0f, %.0f, %.1f, %.2f, %.2e, %.3f, %.2e\n', app.handles.img_id, ...
                app.data.recon.roi(1), app.data.recon.roi(1)+app.data.recon.roi(3), app.data.recon.roi(2), app.data.recon.roi(2)+app.data.recon.roi(4), ...
                app.handles.phase, app.handles.clusterradius, app.handles.scat_ratio, ...
                app.handles.lambda, app.handles.detDistance, app.handles.detPixelsize)
        end
        
        function hologramFigure_KeyPressFcn(app,event)

            switch event.Key
                case 'uparrow'
                    app.handles.ycenter = app.handles.ycenter + 1;
                case 'downarrow'
                    app.handles.ycenter = app.handles.ycenter - 1;
                case 'leftarrow'
                    app.handles.xcenter = app.handles.xcenter - 1;
                case 'rightarrow'
                    app.handles.xcenter = app.handles.xcenter + 1;
            end
            refresh_hologram(app, event);
        end
        
        function update_clusterradius(app,event,newval)
            app.handles.clusterradius = newval;
            app.clusterradius_edit.Value = app.handles.clusterradius;
            app.clusterradius_slider.Value = app.handles.clusterradius;
            decon_checkbox_Callback(app, event);
        end
        
        function db_uitable_KeyPressFcn(app, event)
            selection = app.handles.db.uitable.Selection;
            if isempty(selection)
                if isfield(app.data, 'last_selection')
                    selection = app.data.last_selection;
                else
                    selection = [app.handles.fileIndex, 1];
                end
            end
%                         selection(1) = app.handles.fileIndex;
            switch event.Key
                case {'uparrow', 'downarrow', 'leftarrow', 'rightarrow'}
% %                 %                 case 'uparrow', app.handles.db.uitable.Selection(1) = max([1,app.handles.db.uitable.Selection(1)-1]);
% %                 %                 case 'downarrow', app.handles.db.uitable.Selection(1) = min([size(app.handles.db.uitable.Data, 1), app.handles.db.uitable.Selection(1)+1]);
% %                 %                 case 'leftarrow', app.handles.db.uitable.Selection(2) = max([1, app.handles.db.uitable.Selection(2)-1]);
% %                 %                 case 'rightarrow', app.handles.db.uitable.Selection(2) = min([size(app.handles.db.uitable.Data, 2), app.handles.db.uitable.Selection(2)+1]);
%                 case 'uparrow', selection(1) = max([1,selection(1)-1]);
%                 case 'downarrow', selection(1) = min([size(app.handles.db.uitable.Data, 1), selection(1)+1]);
%                 case 'leftarrow', selection(2) = max([1, selection(2)-1]);
%                 case 'rightarrow', selection(2) = min([size(app.handles.db.uitable.Data, 2), selection(2)+1]);
                otherwise
                    app.data.last_selection = selection;
                    holoShow_KeyPressFcn(app, event)
                                        app.handles.db.uitable.Selection = selection;
                    figure(app.handles.db.figure)
                    app.handles.db.uitable.Selection = selection;
            end
%             app.handles.db.uitable.Selection = selection;
             
        end
        
        function dbFigureKeyPressFcn(app, event)
            selection = app.handles.db.uitable.Selection;
            if isempty(selection)
                if isfield(app.data, 'last_selection')
                    selection = app.data.last_selection;
                else
                    selection = [app.handles.fileIndex, 1];
                end
            end
%             selection(1) = app.handles.fileIndex;
            switch event.Key
%                 case {'uparrow', 'downarrow', 'leftarrow', 'rightarrow'}
%                 case 'uparrow', app.handles.db.uitable.Selection(1) = max([1,app.handles.db.uitable.Selection(1)-1]);
%                 case 'downarrow', app.handles.db.uitable.Selection(1) = min([size(app.handles.db.uitable.Data, 1), app.handles.db.uitable.Selection(1)+1]);
%                 case 'leftarrow', app.handles.db.uitable.Selection(2) = max([1, app.handles.db.uitable.Selection(2)-1]);
%                 case 'rightarrow', app.handles.db.uitable.Selection(2) = min([size(app.handles.db.uitable.Data, 2), app.handles.db.uitable.Selection(2)+1]);
                case 'uparrow', selection(1) = max([1,selection(1)-1]);
                case 'downarrow', selection(1) = min([size(app.handles.db.uitable.Data, 1), selection(1)+1]);
                case 'leftarrow', selection(2) = max([1, selection(2)-1]);
                case 'rightarrow', selection(2) = min([size(app.handles.db.uitable.Data, 2), selection(2)+1]);
                otherwise
%                     app.data.last_selection = selection;
% %                     holoShow_KeyPressFcn(app, event)
%                     app.handles.db.uitable.Selection = selection;
% %                    figure(app.handles.db.figure)
%                     app.handles.db.uitable.Selection = selection;
%                     app.data.last_selection = selection;
            end
%             scroll(app.handles.db.uitable, 'cell', selection)
            app.handles.db.uitable.Selection = selection;
%              app.data.last_selection = selection;

%             app.handles.db.uitable.SelectionType = 'cell';
%                     app.handles.db.uitable.Selection = selection;

            
        end
        
    end

    methods (Access = public)

        function select_hologram(app, event)
            app.holoShow.Interruptible = 0;
            % TO DO: CHANGE HARD CODED MASKING
            app.handles.fileIndex = app.filenames_listbox.Value; % get index of selection

            if isgraphics(app.handles.db.figure)
                newRow = app.handles.fileIndex;
                removeStyle(app.handles.db.uitable);
                s=uistyle('BackgroundColor', [0.95, 0.76, 0.72]);
                addStyle(app.handles.db.uitable, s, 'row', newRow);
                drawnow;
                %                 displayRow = find(app.handles.db.uitable.DisplayData.BunchID == str2double(app.filenames_listbox.Items{app.filenames_listbox.Value}));
                %                 scroll(app.handles.db.uitable, 'row', displayRow);
            end

            if iscell(app.handles.filenames)
                app.handles.currentFile = app.handles.filenames{app.handles.fileIndex};
            else
                app.handles.currentFile = app.handles.filenames;
            end

            %% READ RAW DATA

            fprintf('loading file: %s ...', app.handles.currentFile);
            [~,~,app.handles.ext] = fileparts(fullfile(app.handles.pathname,app.handles.currentFile));
            
            app.masking.gap = ones(1024);
            app.masking.center = ones(1024);

            switch app.handles.ext

                case '.dat'
                    load('data/masks/mask.mat', 'mask', 'drawnMask');
                    app.handles.origmask = (~mask).*drawnMask;
                    app.handles.img_id = app.handles.currentFile(1:end-4);
                    app.handles.hologram.orig = dlmread(fullfile(app.handles.pathname,app.handles.currentFile));
                    app.handles.mask = app.handles.origmask;
                    app.data.run = str2double(app.handles.currentFile(2:5));
                    
                case '.mat'
                    app.handles.img_id = app.handles.currentFile(1:end-4);
                    load(fullfile(app.handles.pathname,app.handles.currentFile), 'data');
                    app.handles.hologram.orig = data.hologram; %#ok<ADPROPLC>
                    if strcmp(app.handles.currentFile(7:11), 'frame')
                        app.handles.hologram.orig = fliplr(rot90(app.handles.hologram.orig));

                        % HARD CODED MASKING FOR LCLS2014 DATA
                        app.handles.hologram.orig(229, 1:513) = 0;
                        app.handles.hologram.orig(230, 1:513) = 0;
                        app.handles.hologram.orig(231, 1:20) = 0;
                        app.handles.hologram.orig(607, 1:21) = 0;
                        app.handles.hologram.orig(1:1024, 1:24) = 0;
                        app.handles.hologram.orig(1:1024, 1000:1024) = 0;
                        app.handles.hologram.orig = app.handles.hologram.orig(1:1024,1:1024);
                        app.handles.hologram.orig(512:end,510:end) = simpleshift(app.handles.hologram.orig(512:end,510:end) , [0,12]);
                        app.handles.hologram.orig(1:511,513:end) = simpleshift(app.handles.hologram.orig(1:511,513:end) , [0,12]);
                        app.handles.hologram.orig(512:end,:) = simpleshift(app.handles.hologram.orig(512:end,:) , [13,-1]);
                    end

                case '.cxi'
                    app.handles.img_id = sprintf('%s_%05d', app.handles.first_file, app.handles.fileIndex);
                    app.handles.hologram.orig = h5read(fullfile(app.handles.pathname, app.handles.first_file), app.handles.h5path.pnCCD, [1 1 app.handles.fileIndex],[1024 1024 1]);
                    try
                        app.handles.hummingbird_mask = h5read(fullfile(app.handles.pathname, app.handles.first_file), app.handles.h5path.mask);
                    catch
                        warning('no hummingbird mask found!')
                        app.handles.hummingbird_mask = ones(size(app.handles.hologram.orig));
                    end
                    app.data.run = str2double(app.handles.first_file(2:5));
                case '.h5'
                    app.handles.img_id = sprintf('%s_%05d', app.handles.first_file, app.db.BunchID(app.handles.fileIndex));
                    fprintf(' Index %d,\tBunch ID %d', app.handles.fileIndex, app.db.BunchID(app.handles.fileIndex));
                    app.handles.hologram.orig = h5read(fullfile(app.handles.pathname, app.handles.first_file), app.handles.h5path.pnCCD, [1+app.handles.img_offset 1 app.handles.fileIndex],[1074 1024 1]);
                    try
                        app.handles.hummingbird_mask = h5read(fullfile(app.handles.pathname, app.handles.first_file), app.handles.h5path.mask, [1+app.handles.img_offset 1],[1074 1024]);
                    catch
                        warning('no hummingbird mask found!')
                        app.handles.hummingbird_mask = ones(size(app.handles.hologram.orig));
                    end
%                     writematrix(app.handles.hummingbird_mask, 'data\masks\hummingbird_mask.dat')
                    if strcmp(app.config.experiment, 'FLASH2017')
                        app.handles.refined_mask = readmatrix('FLASH2017_refined_mask.dat');
                        app.handles.mask = app.handles.refined_mask;
                        [app.handles.hologram.orig, app.handles.origmask] = detector_offset_correction(app.handles.hologram.orig, app.handles.refined_mask, app.handles.detDistance);
%                         size(app.handles.hologram.orig)
                    end
                    
                    hCenter = ceil(size(app.handles.hologram.orig)/2);

                    app.handles.hologram.orig = app.handles.hologram.orig(...
                        hCenter(1)-511:hCenter(1)+512, hCenter(2)-511:hCenter(2)+512);

                    %         app.handles.hologram.orig = app.handles.hologram.orig(31:1024+30,1:1024);
                    %         load('test_int_correction.mat');
                    %         int_corr(isnan(int_corr))=0;
                    %         app.handles.hologram.orig = app.handles.hologram.orig.*int_corr;
                    app.data.run = str2double(app.handles.first_file(2:5));

            end
            if ~isempty(app.handles.detDistArray)
                idx = find(app.data.run >= app.handles.detDistArray(1,:), 1, 'last');
                app.handles.detDistance = app.handles.detDistArray(2,idx);
            end
            if ~isempty(app.handles.gainArray)
                idx = find(app.data.run >= app.handles.gainArray(1,:), 1, 'last');
                app.handles.gain = app.handles.gainArray(2,idx);
            end
            app.handles.photon_adu = app.handles.photon_adu/app.handles.gain;
            if app.handles.rotatePNCCD
                app.handles.hologram.orig = rot90(app.handles.hologram.orig);
            end
            app.handles.hologram.orig = double(app.handles.hologram.orig);
            app.handles.mask = double(app.handles.mask);
            app.handles.origmask = double(app.handles.origmask);

            fprintf(' done! \n'); 

            %% APPLY IMAGE CORRECTION

            if app.applyHologramCorrection_checkbox.Value
                fprintf('applying image correction ...');
                mask_script(app, event);
                fprintf(' done!\n');
            else
                app.handles.hologram.masked = app.handles.hologram.orig;
                app.handles.mask = ones(size(app.handles.hologram.masked), 'like', app.handles.hologram.masked);
                app.handles.hardmask =  ones(size(app.handles.hologram.masked), 'like', app.handles.hologram.masked);
            end

            app.data.size = double(size(app.handles.hologram.masked));
            app.data.center = double(round(app.data.size)/2+1);
            [app.data.xx, app.data.yy] = meshgrid((1:app.data.size(2))-app.data.center(2), (1:app.data.size(1))-app.data.center(1));

            app.handles.hologram.masked = app.handles.hologram.masked.*exp(1i*app.handles.phaseOffset);

            %% CREATE HOLOGRAM FIGURE AND PLOT

            if ~isgraphics(app.handles.hologram.figure)
                app.handles.hologram.figure = figure('Name','hologram');
                app.handles.hologram.figure.Tag = 'holoShow.figures.hologram';
                app.handles.hologram.figure.KeyPressFcn = createCallbackFcn(app, @hologramFigure_KeyPressFcn, true);
                app.handles.hologram.figure.BusyAction = 'cancel';
                app.handles.hologram.axes = axes('parent', app.handles.hologram.figure);
                %     app.handles.hologramI = imagesc(log10(abs(app.handles.hologram.masked)), 'parent', app.handles.hologram.axes);
                app.handles.hologramI = imagesc(app.handles.hologram.axes, abs(app.handles.hologram.masked)); 
                hold(app.handles.hologram.axes, 'on');
                r = (1:50:512);
                phi = linspace(0,2*pi,50)';
                phi = [phi;nan];
                x = reshape(cos(phi)*r,1,[]);
                y = reshape(sin(phi)*r,1,[]);
                app.handles.hologramRings = plot(x+513,y+513,'--', 'color', [1 1 1]*.2);
                axis(app.handles.hologram.axes, 'image');
                app.handles.hologram.axes.ColorScale = 'log';
                % app.handles.hologram.axes.CLim(1) = 1;
                colormap(app.handles.hologram.axes, jet);
                app.handles.hologram.colorbar = colorbar(app.handles.hologram.axes);
                app.handles.hologram.colorbar.Label.String = 'signal in a.u.';
                app.handles.hologram.axes.Title.String = 'scattering pattern';
                app.handles.hologram.figure.Position(1:2) = [1,app.parameter.screensize(4)-app.handles.hologram.figure.Position(4)];
%                 app.handles.hologram.figure.Position(2) = app.handles.hologram.figure.Position(2)-80;
            else
                app.handles.hologramI.CData = abs(app.handles.hologram.masked);
                app.handles.hologramI.AlphaData = abs(app.handles.hologram.masked);
            end

%             app.handles.hologram.axes.CLim = [1, app.handles.adu_max/app.handles.gain];
            app.handles.hologram.axes.CLim = [.1, app.handles.adu_max/app.handles.photon_adu];
            
%             app.handles.hologram.axes.CLim(2) = max(app.handles.hologramI.CData(:));

            %% CREATE RECONSTRUCTION FIGURE AND PLOT

            app.handles.recon = fftshift(ifft2(fftshift(app.handles.hologram.masked))); % reconstruction

            if ~isgraphics(app.handles.reconstruction.figure)
                app.handles.reconstruction.figure = figure('Name','reconstruction');
                app.handles.reconstruction.figure.Tag = 'holoShow.figures.reconstruction';
                app.handles.reconstruction.figure.BusyAction = 'cancel';
                app.handles.reconstruction.figure.KeyPressFcn = createCallbackFcn(app, @holoShow_KeyPressFcn, true);
                app.handles.reconAxes = axes('parent', app.handles.reconstruction.figure);
                app.handles.reconI = imagesc(part_and_scale(app.handles.recon, app.handles.partSwitch),...
                    'parent', app.handles.reconAxes); % plot
                axis(app.handles.reconAxes, 'image');
                app.handles.reconColorbar = colorbar(app.handles.reconAxes);
                if get(app.scale_checkbox, 'Value')
                    caxis(app.handles.reconAxes, [app.handles.minScale, app.handles.maxScale]);
                end
                app.handles.reconAxes.Title.String = 'patterson map';
                app.handles.reconstruction.figure.Position(1:2) = [app.handles.hologram.figure.Position(1)+app.handles.hologram.figure.Position(3),app.parameter.screensize(4)-app.handles.hologram.figure.Position(4)];
%                 app.handles.reconstruction.figure.Position(2) = app.handles.reconstruction.figure.Position(2)-80;
            else
                app.handles.reconI.CData = part_and_scale(app.handles.recon, app.handles.partSwitch);
            end

            colormap(app.handles.reconAxes, app.handles.colormap);
            app.handles.reconAxes.XLim = [1, size(app.handles.hologram.orig,2)];
            app.handles.reconAxes.YLim = [1, size(app.handles.hologram.orig,1)];

            %% SET ROI TO WHOLE IMAGE
            app.data.recon.roi(1) = 1;
            app.data.recon.roi(2) = 1;
            app.data.recon.roi(3) = size(app.handles.hologram.orig,1)-1;
            app.data.recon.roi(4) = size(app.handles.hologram.orig,2)-1;
            
            %% SET VALUSE FROM TABLE
            if isfield(app.handles,'db') && isfield(app.handles.db,'uitable') && isgraphics(app.handles.db.uitable)
                app.handles.phase = iif(isnan(app.handles.db.uitable.Data.deltaZ(app.handles.fileIndex)), 0, app.handles.db.uitable.Data.deltaZ(app.handles.fileIndex));
                app.phase_edit.Value = app.handles.phase;
                app.phase_slider.Value = app.handles.phase;
                app.handles.scat_ratio = iif(isnan(app.handles.db.uitable.Data.scattRatio(app.handles.fileIndex)), -2, app.handles.db.uitable.Data.scattRatio(app.handles.fileIndex));
                app.scattRatio_slider.Value = app.handles.scat_ratio;
                app.scattRatio_edit.Value = app.handles.scat_ratio;
                app.handles.clusterradius = iif(isnan(app.handles.db.uitable.Data.clusterSize(app.handles.fileIndex)), 45, app.handles.db.uitable.Data.clusterSize(app.handles.fileIndex));
                app.clusterradius_edit.Value = app.handles.clusterradius;
            end
%             app.data.recon.roi = [1,1,1023,1013];
            %% REFRESH RECONSTRUCTION PLOT
            refreshImage(app, event);

        end

        function changeColorScheme(app)

            % Define Colors (Mariana Color Scheme)
            app.colors.black = [0 0 0];
            app.colors.blue = [0.4000 0.5961 0.8000];
            app.colors.blue2 = [0.3045 0.3506 0.3955];
            app.colors.blue3 = [0.2040 0.2393 0.2760];
            app.colors.blue4 = [0.3915 0.4489 0.5085];
            app.colors.blue5 = [0.3744 0.7028 0.7056];
            app.colors.blue6 = [0.6528 0.6756 0.7272];
            app.colors.green = [0.5996 0.7792 0.5808];
            app.colors.grey = [0.2000 0.2000 0.2000];
            app.colors.orange = [0.9762 0.6820 0.3438];
            app.colors.orange2 = [0.9325 0.5766 0.1675];
            app.colors.orange3 = [0.9808 0.7814 0.3792];
            app.colors.pink = [0.7760 0.5840 0.7733];
            app.colors.red = [0.9265 0.3735 0.3920];
            app.colors.red2 = [0.9762 0.4812 0.3438];
            app.colors.white = [1 1 1];
            app.colors.white2 = [0.9700 0.9700 0.9700];
            app.colors.white3 = [0.8464 0.8692 0.9136];
            app.colors.white4 = [0.7184 0.7603 0.8416];

            if ~isfield(app.config, 'colorScheme')
                app.config.colorScheme = 'white';
            end

            switch app.config.colorScheme

                case 'white'
                    set(0, 'defaultTextColor', [0 0 0])

                    set(0, 'defaultFigureColor', [1 1 1])

                    set(0, 'defaultAxesXColor', [0 0 0])
                    set(0, 'defaultAxesYColor', [0 0 0])
                    set(0, 'defaultAxesZColor', [0 0 0])

                    set(0, 'defaultUiControlBackgroundColor', [1 1 1])
                    set(0, 'defaultUIContainerBackgroundColor', [1 1 1])
                    set(0, 'defaultUiPanelBackgroundColor', [1 1 1])
                    set(0, 'defaultUiButtonGroupBackgroundColor', [1 1 1])

                    app.holoShow.Color = [1 1 1];
                    app.uipanel1.BackgroundColor = [1 1 1];
                    app.uipanel2.BackgroundColor = [1 1 1];
                    app.uipanel3.BackgroundColor = [1 1 1];
                    app.uipanel4.BackgroundColor = [1 1 1];
                    app.colormap_buttongroup.BackgroundColor = [1 1 1];
                    app.part_buttongroup.BackgroundColor = [1 1 1];

                case 'dark'
                    % Pick Colors Here
                    app.colors.Background = app.colors.blue2;
                    app.colors.Foreground = app.colors.white2;
                    app.colors.Font = app.colors.white2;

                    app.colors.Button.Background = app.colors.blue3;
                    app.colors.Button.Font = app.colors.white3;

                    app.colors.EditField.Background = (app.colors.white2+app.colors.white2)/2;
                    app.colors.EditField.Font = app.colors.blue2;

                    % Set Colors of main figure
                    propertyNames = properties(app);

                    for iProp=1:numel(propertyNames)

                        switch class(app.(propertyNames{iProp}))

                            case {'matlab.ui.Figure'}
                                app.(propertyNames{iProp}).Color = app.colors.Background;
                            case {'matlab.ui.container.Panel', 'matlab.ui.container.ButtonGroup'}
                                app.(propertyNames{iProp}).BackgroundColor = app.colors.Background;
                                app.(propertyNames{iProp}).ForegroundColor = app.colors.Foreground;
                            case {'matlab.ui.control.CheckBox', 'matlab.ui.control.RadioButton', 'matlab.ui.control.Slider'}
                                app.(propertyNames{iProp}).FontColor = app.colors.Font;
                            case {'matlab.ui.control.Button'}
                                app.(propertyNames{iProp}).BackgroundColor = app.colors.Button.Background;
                                app.(propertyNames{iProp}).FontColor = app.colors.Button.Font;
                            case {'matlab.ui.control.Label'}
                                app.(propertyNames{iProp}).BackgroundColor = app.colors.Background;
                                app.(propertyNames{iProp}).FontColor = app.colors.Button.Font;
                            case {'matlab.ui.control.ListBox', 'matlab.ui.control.EditField', 'matlab.ui.control.DropDown'}
                                app.(propertyNames{iProp}).BackgroundColor = app.colors.EditField.Background;
                                app.(propertyNames{iProp}).FontColor = app.colors.EditField.Font;
                            otherwise

                        end

                    end

                    % Redefine default colors for future graphic objects
                    set(0, 'defaultTextColor', app.colors.Font)

                    set(0, 'defaultFigureColor', app.colors.Background)

                    set(0, 'defaultAxesXColor', app.colors.Font)
                    set(0, 'defaultAxesYColor', app.colors.Font)
                    set(0, 'defaultAxesZColor', app.colors.Font)

                    set(0, 'defaultUiControlBackgroundColor', app.colors.Background)
                    set(0, 'defaultUIContainerBackgroundColor', app.colors.Background)
                    set(0, 'defaultUiPanelBackgroundColor', app.colors.Background)
                    set(0, 'defaultUiButtonGroupBackgroundColor', app.colors.Background)

                otherwise

            end

        end

        function changeMatlabDefaults(app)

            % reset graphics to default
            reset(0);

            % set color scheme
            app.changeColorScheme();

            set(0, 'defaultFigureColormap',gray)

            set(0, 'defaultAxesYDir', 'normal')
            set(0, 'defaultAxesOuterPosition', [.0 .01 1 .99])
            set(0, 'defaultAxesBox', 'on')
            set(0, 'defaultAxesDataAspectRatio', [1 1 1])
            %             set(0, 'defaultAxesYDir', 'normal')

            set(0, 'defaultImageCreateFcn', @(src, evt) newImageCreateFcn(app, src, evt))

            %             set(0, 'defaultLineCreateFcn', @(src, evt) newLineCreateFcn(app, src, evt))
            set(0, 'defaultLineLineWidth', 1)

        end

        function loadExperimentConfig(app)

            fileID = fopen(fullfile(app.handles.sourcepath, 'src/config/', ...
                [app.config.experiment, '.cfg']));
            while ~feof(fileID)
                eval(char(fgetl(fileID)))
            end
            fclose(fileID);

            
            app.wavelength_edit.Value = num2str(app.handles.lambda*1e9);
            app.detDist_edit.Value = num2str(app.handles.detDistance);
            app.load_mask_checkbox.Value = app.handles.load_mask;
            app.cm_checkbox.Value = app.handles.do_cm;
            app.min_edit.Value = num2str(app.handles.minScale);
            app.max_edit.Value = num2str(app.handles.maxScale);
            app.highpass_checkbox.Value = app.handles.HPfiltering;
            app.lowpass_checkbox.Value = app.handles.LPfiltering;
            app.highpass_edit.Value = num2str(app.handles.HPfrequency);
            app.lowpass_edit.Value = num2str(app.handles.LPfrequency);
            app.clusterradius_edit.Value = app.handles.clusterradius;
            app.scattRatio_slider.Value = app.handles.scat_ratio;
            app.scattRatio_edit.Value = app.handles.scat_ratio;
            app.range_edit.Value = app.handles.phaseLimit;
            range_editValueChanged(app, []);
            if gpuDeviceCount<1, app.handles.gpu = false; end
        end

        function loadUserConfig(app)

            if ~exist(fullfile(app.handles.sourcepath, 'src/config/userconfig.cfg'),'file')
                copyfile(...
                    fullfile(app.handles.sourcepath, 'src/config/userconfig-template.cfg'), ...
                    fullfile(app.handles.sourcepath, 'src/config/userconfig.cfg'));
            end

            % Check user config
            fprintf('loading user config ...\n\n')
            app.config.default = false;
            fileID = fopen(fullfile('src/config/userconfig.cfg'));
            while ~feof(fileID)
                configLine = char(fgetl(fileID));
                fprintf('\t%s\n', configLine)
                eval(configLine)
                if app.config.default; break; end
            end
            fprintf('\n\t\t\tdone!\n\n')
            fclose(fileID);

            if app.config.default
                app.config.experiment = app.config_dropdown.Items{app.config_dropdown.Value};
                app.loadExperimentConfig();
            else
                % Change experiment experiment. Callback will fire and read the
                % Experiment Config File
                app.config_dropdown.Value = find(strcmp(...
                    app.config_dropdown.Items, app.config.experiment));

                % Read userconfig again to change other parameters
                if ~app.config.default
                    fileID = fopen(fullfile('src/config/userconfig.cfg'));
                    while ~feof(fileID)
                        eval(char(fgetl(fileID)))
                    end
                    fclose(fileID);
                end
            end

        end

        function getNoiseFromArea(app)

            % Create figure
            if ~isfield(app.handles.noise, 'figure')
                app.handles.noise.figure = gobjects(1);
            end
            if ~isgraphics(app.handles.noise.figure)
                app.handles.noise.figure = figure('Name', 'Noise Spectrum Figure');
                app.handles.noise.figure.Tag = 'holoShow.figures.noise';
                app.handles.noise.BusyAction = 'cancel';
                app.handles.noise.figure.Units = 'normalized';
                app.handles.noise.figure.Position(3) = 2*app.handles.noise.figure.Position(3);

                app.handles.noise.pattersonAxes = axes(app.handles.noise.figure, 'Units', 'normalized', 'Position', [.05 .05 .35 .9]);
                app.handles.noise.pattersonImage = imagesc(app.handles.noise.pattersonAxes, nan(size(app.handles.recon)));
                app.handles.noise.spectrumAxes = axes(app.handles.noise.figure, 'Units', 'normalized',  'Position', [.45 .1 .5 .8]);
                app.handles.noise.reconSpectrumPlot = semilogy(app.handles.noise.spectrumAxes, nan, 'DisplayName', 'recon');
                app.handles.noise.spectrumAxes.NextPlot = 'add';
                grid(app.handles.noise.spectrumAxes, 'on');
                app.handles.noise.noiseSpectrumPlot = semilogy(app.handles.noise.spectrumAxes, nan, 'DisplayName', 'noise');
                app.handles.noise.snrSpectrumPlot = semilogy(app.handles.noise.spectrumAxes, nan, '--', 'DisplayName', 'SNR');
                legend(app.handles.noise.spectrumAxes);
                app.handles.noise.spectrumAxes.Title.String = 'Spectrum';
                app.handles.noise.spectrumAxes.PlotBoxAspectRatio = [1.619 1 1];

                app.handles.noise.saveButton = uicontrol('parent', app.handles.noise.figure, 'style', 'pushbutton');
                app.handles.noise.figure.Units = 'pixels';
                fp = app.handles.noise.figure.Position;
                app.handles.noise.saveButton.Position = [fp(3)-130 fp(4)-37 100 22];
                app.handles.noise.saveButton.String = 'save';
                app.handles.noise.saveButton.Callback = createCallbackFcn(app, @saveNoiseFigureButtonBushedFcn, true);
%             else
%                 figure(app.handles.noise.figure)
            end

            % Plot Patterson Map to pick noise reference area
            app.handles.noise.pattersonImage.CData = abs(app.handles.recon);
            app.handles.noise.pattersonAxes.DataAspectRatio = [1 1 1];
            app.handles.noise.pattersonAxes.ColorScale = 'log';
            app.handles.noise.pattersonAxes.Colormap = jet;

            try delete(app.handles.noise.dataRectangle);end %#ok<*TRYNC>
            try delete(app.handles.noise.noiseRectangle);end

            app.handles.noise.dataRectangle = drawrectangle(app.handles.noise.pattersonAxes, ...
                'Position', app.data.recon.roi, 'Color', [0 0 1], ...
                'Label', 'data', 'LabelVisible', 'hover');
            app.handles.noise.dataRectangle.UserData.ListenerMovingROI = ...
                addlistener(app.handles.noise.dataRectangle, 'MovingROI', @(src, event) dataMovingROI(app, src, event));
            app.handles.noise.dataRectangle.UserData.ListenerROIMoved = ...
                addlistener(app.handles.noise.dataRectangle, 'ROIMoved', @(src, event) dataROIMoved(app, src, event));
            dataROIMoved(app)

            app.handles.noise.noiseRectangle = drawrectangle(app.handles.noise.pattersonAxes, ...
                'Position', app.data.recon.roi, 'Color', [1 0 0], ...
                'Label', 'noise', 'LabelVisible', 'hover');
            app.handles.noise.noiseRectangle.UserData.ListenerMovingROI = ...
                addlistener(app.handles.noise.noiseRectangle, 'MovingROI', @(src, event) noiseMovingROI(app, src, event));
            app.handles.noise.noiseRectangle.UserData.ListenerROIMoved = ...
                addlistener(app.handles.noise.noiseRectangle, 'ROIMoved', @(src, event) noiseROIMoved(app, src, event));

            app.handles.noise.noiseRectangle.draw;

        end

        function saveNoiseFigureButtonBushedFcn(app, ~, ~)
            switch app.config.experiment
                case 'FLASH2017'
                    saveName = sprintf('noise_r%04d_id%s.csv', app.thisrun.run, app.filenames_listbox.Items{app.filenames_listbox.Value});
                case 'LCLS2016'
                    saveName = sprintf('noise_r%04d_h%s.csv', app.thisrun.run, app.filenames_listbox.Items{app.filenames_listbox.Value}(7:11));
                case 'LCLS2014'
                    saveName = sprintf('noise_%s.csv', app.filenames_listbox.Items{app.filenames_listbox.Value});
            end

            saveDir = fullfile(app.handles.sourcepath, ...
                sprintf('data/noise/%s', app.config.experiment));
            if ~exist(saveDir, 'dir')
                mkdir(saveDir);
            end
            saveFullFilePath = fullfile(saveDir, saveName);
            fprintf('\tSaving SNR data to file %s ...\n', saveFullFilePath);
            saveData = [app.data.noise.radii; app.data.snr.theta; app.data.snr.q; app.data.snr.res; app.data.recon.roiSpectrum; app.data.noise.spectrum; app.data.snr.spectrum];
            writematrix(saveData, saveFullFilePath)
            fprintf('\t\t done!\n')
        end

        function saveSnrFigureButtonBushedFcn(app, ~, ~)
            switch app.config.experiment
                case 'FLASH2017'
                    shotName = sprintf('r%04d_id%s.csv', app.thisrun.run, app.filenames_listbox.Items{app.filenames_listbox.Value});
                case 'LCLS2016'
                    shotName = sprintf('r%04d_h%s.csv', app.thisrun.run, app.filenames_listbox.Items{app.filenames_listbox.Value}(7:11));
                case 'LCLS2014'
                    shotName = sprintf('%s.csv', app.filenames_listbox.Items{app.filenames_listbox.Value});
            end

            % SNR
            prefix = 'snr';
            saveDir = fullfile(app.handles.sourcepath, sprintf('data/%s/%s', prefix, app.config.experiment));
            if ~exist(saveDir, 'dir'); mkdir(saveDir); end
            fullFilePath = fullfile(saveDir, sprintf('%s_%s', prefix, shotName));
            fprintf('\tSaving %s data to file %s ...\n', prefix, fullFilePath);
            sData = [app.data.noise.radii; app.data.snr.theta; app.data.snr.q; app.data.snr.res; app.data.recon.roiSpectrum; app.data.noise.spectrum; app.data.snr.spectrum];
            writematrix(sData, fullFilePath)
            fprintf('\t\t done!\n')

            % Contrast
            prefix = 'contrast';
            saveDir = fullfile(app.handles.sourcepath, sprintf('data/%s/%s', prefix, app.config.experiment));
            if ~exist(saveDir, 'dir'); mkdir(saveDir); end
            fullFilePath = fullfile(saveDir, sprintf('%s_%s', prefix, shotName));
            fprintf('\tSaving %s data to file %s ...\n', prefix, fullFilePath);
            sData = [app.data.contrast.radii; app.data.contrast.q; app.data.contrast.res; app.data.contrast.contrast];
            writematrix(sData, fullFilePath)
            fprintf('\t\t done!\n')

            % FRC
            prefix = 'snr';
            saveDir = fullfile(app.handles.sourcepath, sprintf('data/%s/%s', prefix, app.config.experiment));
            if ~exist(saveDir, 'dir'); mkdir(saveDir); end
            fullFilePath = fullfile(saveDir, sprintf('%s_%s', prefix, shotName));
            fprintf('\tSaving %s data to file %s ...\n', prefix, fullFilePath);
            sData = [app.data.frc.x; app.data.frc.q; app.data.frc.res; app.data.frc.frc];
            writematrix(sData, fullFilePath)
            fprintf('\t\t done!\n')
        end

        function dataMovingROI(app, ~, event)

            app.data.recon.roi = round(event.CurrentPosition);
            app.data.recon.roi(1) = max([app.data.recon.roi(1), 1]);
            app.data.recon.roi(2) = max([app.data.recon.roi(2), 1]);
            app.data.recon.roi(3) = min([ app.data.recon.roi(3), ...
                (size(app.handles.recon,1) - app.data.recon.roi(1) )]);
            app.data.recon.roi(4) = min([ app.data.recon.roi(4), ...
                (size(app.handles.recon,2) - app.data.recon.roi(2)) ]);

            app.handles.noise.pattersonAxes.Title.String = ['Recon Area: ', ...
                mat2str(app.data.recon.roi), ' px'];

        end

        function dataROIMoved(app, ~, ~)

            disp(['New recon area: ' mat2str(app.data.recon.roi), ' px'])

            % Get Data from roi and normalize it on the number of pixels
            app.data.recon.roiSignal = app.handles.recon(...
                app.data.recon.roi(2):app.data.recon.roi(2)+app.data.recon.roi(4), ...
                app.data.recon.roi(1):app.data.recon.roi(1)+app.data.recon.roi(3));
            app.data.recon.roiSignal = app.data.recon.roiSignal/mean(size(app.data.recon.roiSignal));

            % Calc 2D FT for Noise
            app.data.recon.roiSignalFT = ft2(zeropadding(app.data.recon.roiSignal,1024));
            app.data.recon.roiSpectrum2D = abs(app.data.recon.roiSignalFT).^2;

            app.calc_rmean_range();
            [app.data.recon.roiSpectrum, app.data.recon.roiRadii] = rmean((app.data.recon.roiSpectrum2D), app.handles.rmean.range, [], app.handles.rmean.bins);

            app.data.snr.theta = atan(app.data.recon.roiRadii*75e-6/app.handles.detDistance);
            app.data.snr.q = 4*pi/app.handles.lambda * sin(app.data.snr.theta/2);
            app.data.snr.res = 2*pi./app.data.snr.q;

            app.handles.noise.reconSpectrumPlot.XData = app.data.snr.q;
            app.handles.noise.reconSpectrumPlot.YData = app.data.recon.roiSpectrum;

            if isfield(app.data,'noise')
                if numel(app.data.recon.roiSpectrum) == numel(app.data.noise.spectrum)
                    app.data.snr.spectrum = app.data.recon.roiSpectrum./app.data.noise.spectrum;
                    app.data.snr.radii = (app.data.recon.roiRadii + app.data.noise.radii)/2;
                    app.handles.noise.snrSpectrumPlot.XData = app.data.snr.q;
                    app.handles.noise.snrSpectrumPlot.YData = app.data.snr.spectrum;
                end
            end

        end

        function noiseMovingROI(app, ~, event)

            app.data.noise.roi = round(event.CurrentPosition);
            app.data.noise.roi(1) = max([app.data.noise.roi(1), 1]);
            app.data.noise.roi(2) = max([app.data.noise.roi(2), 1]);
            app.data.noise.roi(3) = min([ app.data.noise.roi(3), ...
                (size(app.handles.recon,1) - app.data.noise.roi(1) )]);
            app.data.noise.roi(4) = min([ app.data.noise.roi(4), ...
                (size(app.handles.recon,2) - app.data.noise.roi(2)) ]);

            app.handles.noise.pattersonAxes.Title.String = ['Noise Area: ', ...
                mat2str(app.data.noise.roi), ' px'];

        end

        function noiseROIMoved(app, ~, ~)

            disp(['New noise area: ' mat2str(app.data.noise.roi), ' px'])

            % Get Data from roi and normalize it on the number of pixels
            app.data.noise.signal = app.handles.recon(...
                app.data.noise.roi(2):app.data.noise.roi(2)+app.data.noise.roi(4), ...
                app.data.noise.roi(1):app.data.noise.roi(1)+app.data.noise.roi(3));
            app.data.noise.signal = app.data.noise.signal/mean(size(app.data.noise.signal));

            % Calc 2D FT for Noise
            app.data.noise.signalFT = ft2(zeropadding(app.data.noise.signal,1024));
            app.data.noise.spectrum2D = abs(app.data.noise.signalFT).^2;

            app.calc_rmean_range();
            [app.data.noise.spectrum, app.data.noise.radii] = rmean((app.data.noise.spectrum2D), app.handles.rmean.range, [], app.handles.rmean.bins);

            app.handles.noise.noiseSpectrumPlot.XData = app.data.snr.q;
            app.handles.noise.noiseSpectrumPlot.YData = app.data.noise.spectrum;

            app.data.snr.spectrum = app.data.recon.roiSpectrum./app.data.noise.spectrum;
            app.data.snr.radii = (app.data.recon.roiRadii + app.data.noise.radii)/2;

            app.handles.noise.snrSpectrumPlot.XData = app.data.snr.q;
            app.handles.noise.snrSpectrumPlot.YData = app.data.snr.spectrum;

            app.handles.noise.spectrumAxes.XLabel.String = 'q [1/m]';
            xlim(app.handles.noise.spectrumAxes, app.data.snr.q([1,end]));
            ytight(app.handles.noise.spectrumAxes);

        end

        function newImageCreateFcn(~, src, ~)

            %             colorbar(src.Parent);
            src.Parent.YDir = 'normal';
            %             src.Parent.XGrid = 'off';
            %             src.Parent.YGrid = 'off';
            %             src.Parent.ZGrid = 'off';

        end

        function newLineCreateFcn(~, src, ~)

            %             src.Parent.XGrid = 'on';
            %             src.Parent.YGrid = 'on';
            %             src.Parent.ZGrid = 'on';
            xtight(src.Parent);

        end

        function refresh_hologram(app, event)

            % Needed if parameter values like center of hologram or shift/slit have
            % changed to update hologram and mask. Updates data and plots.
            %
            % Anatoli Ulmer (2021). HoloShow
            % (https://github.com/anatoli-ulmer/holoShow)

            if app.applyHologramCorrection_checkbox.Value
                mask_script(app, event);
            else
                app.handles.hologram.masked = app.handles.hologram.orig;
                app.handles.mask = ones(1024);
                app.handles.hardmask = ones(1024);
            end
%             app.handles.xcenter = app.handles.xcenter + columnsToShift;
%             app.handles.ycenter = app.handles.ycenter + rowsToshift;
%             disp(app.handles.xcenter)
%             app.handles.hologram.masked = simpleshift(app.handles.hologram.masked, -[app.handles.ycenter,app.handles.xcenter] );
%             app.handles.mask = simpleshift(app.handles.mask, -[app.handles.ycenter,app.handles.xcenter] );
%             app.handles.hologram.masked = app.handles.hologram.masked.*exp(1i*app.handles.phaseOffset);

            app.handles.recon = fftshift(ifft2(fftshift(app.handles.hologram.masked)));
            app.handles.reconImageData = part_and_scale(...
                app.handles.recon(app.data.recon.roi(2):app.data.recon.roi(2)+app.data.recon.roi(4),...
                app.data.recon.roi(1):app.data.recon.roi(1)+app.data.recon.roi(3)),...
                app.handles.partSwitch);

            %% CREATE HOLOGRAM FIGURE AND PLOT
            if ~isgraphics(app.handles.hologram.figure)
                app.handles.hologram.figure = figure('Name','hologram');
                app.handles.hologram.figure.Tag = 'holoShow.figures.hologram';
                app.handles.hologram.figure.BusyAction = 'cancel';
                app.handles.hologram.axes = axes('parent', app.handles.hologram.figure);
                %     app.handles.hologramI = imagesc(log10(abs(app.handles.hologram.masked)), 'parent', app.handles.hologram.axes);
                app.handles.hologramI = imagesc(app.handles.hologram.axes, app.handles.hologram.masked);
                axis(app.handles.hologram.axes, 'image');
                app.handles.hologram.axes.ColorScale = 'log';
                % app.handles.hologram.axes.CLim(1) = 1;
                colormap(app.handles.hologram.axes, jet);
                app.handles.hologram.colorbar = colorbar(app.handles.hologram.axes);
                app.handles.hologram.colorbar.Label.String = 'signal in arb. units';
                app.handles.hologram.axes.Title.String = 'scattering pattern';
                app.handles.hologram.figure.Position(1:2) = [1,app.parameter.screensize(4)-app.handles.hologram.figure.Position(4)];
%                 app.handles.hologram.figure.Position(2) = app.handles.hologram.figure.Position(2)-80;
            else
                app.handles.hologramI.CData = abs(app.handles.hologram.masked);
            end

            app.handles.hologram.axes.CLim(1) = 1;
            app.handles.hologram.axes.CLim(2) = max(app.handles.hologramI.CData(:));

            %% CREATE RECONSTRUCTION FIGURE AND PLOT
            if ~isgraphics(app.handles.reconstruction.figure)
                app.handles.reconstruction.figure = figure('Name','reconstruction');
                app.handles.reconstruction.figure.Tag = 'holoShow.figures.reconstruction';
                app.handles.reconstruction.figure.BusyAction = 'cancel';
                app.handles.reconAxes = axes('parent', app.handles.reconstruction.figure);
                app.handles.reconI = imagesc(app.handles.reconImageData,...
                    'parent', app.handles.reconAxes); % plot
                axis(app.handles.reconAxes, 'image');
                colormap(app.handles.reconAxes, app.handles.colormap);
                app.handles.reconColorbar = colorbar(app.handles.reconAxes);
                if get(app.scale_checkbox, 'Value')
                    caxis(app.handles.reconAxes, [app.handles.minScale, app.handles.maxScale]);
                end
                app.handles.reconAxes.Title.String = 'patterson map';
%                 app.handles.reconstruction.figure.Position(2) = app.handles.reconstruction.figure.Position(2)-80;                
            else
                app.handles.reconI.CData = app.handles.reconImageData;
            end

            if get(app.scale_checkbox, 'Value')
                caxis(app.handles.reconAxes, [app.handles.minScale, app.handles.maxScale]);
            end

            refreshImage(app, event);

        end

        function refreshImage(app, event)

            refreshPhase(app, event);

            reconScalebar(app, app.handles.reconAxes);

            app.handles.reconColorbar.Label.String = sprintf('%s part in a.u.', app.handles.partSwitch);
            app.handles.reconAxes.ColorScale = iif(app.log_checkbox.Value, 'log', 'linear');
        end

        function refreshPhase(app, ~)

            % app.handles.phase = app.phase_slider.Value;
            % app.handles.phase_edit.Value = app.handles.phase;

            if app.decon_checkbox.Value
                app.handles.hologram.propagated = propagateHologram(app.handles.hologram.deconvoluted, ...
                    app.handles.phase, app.handles.lambda, app.handles.detDistance, app.handles.cut_center);
            else
                app.handles.hologram.propagated = propagateHologram(abs(app.handles.hologram.masked), ...
                    app.handles.phase, app.handles.lambda, app.handles.detDistance, app.handles.cut_center);
            end
            % app.handles.hologram.propagated = app.handles.hologram.propagated.*...
            % exp(1i*app.handles.phaseOffset);

            app.handles.recon = ift2(app.handles.hologram.propagated);
            app.handles.reconI.CData = part_and_scale(app.handles.recon(...
                app.data.recon.roi(2):app.data.recon.roi(2)+app.data.recon.roi(4),app.data.recon.roi(1):app.data.recon.roi(1)+app.data.recon.roi(3)),...
                app.handles.partSwitch);
%             app.handles.reconAxes.XLim = [1, 1+app.data.recon.roi(3)];
%             app.handles.reconAxes.YLim = [1, 1+app.data.recon.roi(4)];
            grid(app.handles.reconAxes, false);
            % app.handles.reconAxes.XLim = [app.data.recon.roi(1), app.data.recon.roi(1)+app.data.recon.roi(3)];
            % app.handles.reconAxes.YLim = [app.data.recon.roi(2), app.data.recon.roi(2)+app.data.recon.roi(4)];
            drawnow
        end

        function createDataTable(app)

            if ~isgraphics(app.handles.db.figure)
                app.handles.db.figure = uifigure;
                app.handles.db.figure.Tag = 'holoShow.figures.db';
                app.handles.db.figure.NumberTitle = 'off';
                app.handles.db.figure.Name = 'data table figure';
                app.handles.db.figure.KeyPressFcn = createCallbackFcn(app, @dbFigureKeyPressFcn, true);
                app.handles.db.figure.DeleteFcn = createCallbackFcn(app, @dbFigureDeleteFcn, true);

                app.handles.db.uitable = uitable(app.handles.db.figure);
                app.handles.db.uitable.Tag = 'holoShow.uitables.data';
                app.handles.db.uitable.Units = 'normalized';
                app.handles.db.uitable.RowName = 'numbered';
                app.handles.db.uitable.Position = [.025 .025 .95 .85];
                app.handles.db.uitable.ColumnWidth = 'fit';
                app.handles.db.uitable.ColumnWidth = 'fit';
                app.handles.db.uitable.ColumnEditable = true;
                app.handles.db.uitable.ColumnSortable = true;
                app.handles.db.uitable.CellEditCallback = createCallbackFcn(app, @dbCellEditCallbackFcn, true);
                app.handles.db.uitable.CellSelectionCallback = createCallbackFcn(app, @dbCellSelectionCallback, true);
                app.handles.db.uitable.KeyPressFcn = createCallbackFcn(app, @db_uitable_KeyPressFcn, true);

                app.handles.db.uibutton = uibutton(app.handles.db.figure);
                %                 app.handles.db.uibutton.Units = 'normalized';
                fp = app.handles.db.figure.Position;
                app.handles.db.uibutton.Position = [fp(3)-150 fp(4)-50 100 22];
                app.handles.db.uibutton.Text = 'save';
                app.handles.db.uibutton.ButtonPushedFcn = createCallbackFcn(app, @dbButtonPushedFcn, true);

                %                 app.handles.db.uicontextmenu = uicontextmenu(app.handles.db.figure);
                %                 app.handles.db.uimenu(1) = uimenu(app.handles.db.uicontextmenu,'Text','load shot');
                %                 app.handles.db.uimenu(1).MenuSelectedFcn = createCallbackFcn(app, @dbUiMenuSelectedFcn, true);
                %                 app.handles.db.uitable.ContextMenu = app.handles.db.uicontextmenu;
            end

            app.handles.db.uitable.Data = app.db;
            app.handles.db.uitable.ColumnEditable = ...
                [false(1,4), true(1,size(app.db,2)-4)];
            %             app.handles.db.uitable.Data = app.thisrun.db;

        end

        function loadDB(app)

            dbFullPath = fullfile(app.handles.sourcepath, ...
                sprintf('data/db/%s/db_r%04d.mat', app.config.experiment, app.thisrun.run));
            fprintf('\tLoading data base from file %s ...\n', dbFullPath);
            if exist(dbFullPath,"file")
                loadedData = load(dbFullPath, "-mat", "db");
                app.db = loadedData.db;
            else
                fprintf('\t\tFile not found. Creating new data base ...\n')
                app.db = table;
                app.db.run = app.thisrun.run*ones(app.thisrun.nbr_images,1);
                app.db.BunchID = app.handles.entrylist;
                app.db.LitPixel = h5read(fullfile(app.handles.pathname, app.handles.first_file), app.handles.h5path.hitscore_litpixel);
                app.db.MultiScore = -ones(app.thisrun.nbr_images,1);
                app.db.MultiOld = h5read(fullfile(app.handles.pathname, app.handles.first_file), app.handles.h5path.multiscore_patterson);
                app.db.isMulti = false(app.thisrun.nbr_images,1);
                app.db.isHolo = false(app.thisrun.nbr_images,1);
                app.db.isGood = false(app.thisrun.nbr_images,1);
                app.db.deltaZ = nan(app.thisrun.nbr_images,1);
                app.db.clusterSize = nan(app.thisrun.nbr_images,1);
                app.db.scattRatio = nan(app.thisrun.nbr_images,1);
                app.db.resolution = nan(app.thisrun.nbr_images,1);
            end
            fprintf('\t\t done!\n')

        end

        function saveDB(app)
            try
                dbFilePath = fullfile(app.handles.sourcepath, ...
                    sprintf('data/db/%s', app.config.experiment));
                if ~exist(dbFilePath, 'dir')
                    mkdir(dbFilePath);
                end
                dbFileName = sprintf('db_r%04d.mat', app.thisrun.run);
                dbFullPath = fullfile(dbFilePath, dbFileName);
                fprintf('\tSaving data base to file %s ...\n', dbFullPath);
                db = app.db; %#ok<ADPROP> 
                save(dbFullPath, "db", '-mat')
                clear db;
                fprintf('\t\t done!\n')
            catch
                warning('db not saved\n')
            end
        end

        function dbButtonPushedFcn(app, ~)
            saveDB(app);
        end

        function dbCellEditCallbackFcn(app, event)
            app.db{event.Indices(1),event.Indices(2)} = event.NewData;
        end

        function dbCellSelectionCallback(app, event)

            if ~(app.handles.db.uitable.ColumnEditable(event.Indices(1,2)))
%                 if strcmp(event.Source.Parent.SelectionType, 'open')
                    app.filenames_listbox.Value = event.Indices(1);
                    select_hologram(app, event); % GRAB HOLOGRAM
%                 end
            end

        end

        %         function dbUiMenuSelectedFcn(app, event)
        %                 app.filenames_listbox.Value = event.Indices(1);
        %                 select_hologram(app, event); % GRAB HOLOGRAM
        %         end

        function dbFigureDeleteFcn(app, ~)

            app.saveDB();

        end

        function createHitscorePlot(app)

            if ~isgraphics(app.handles.hitscore.figure)
                thisLineWidth = 1.5;
                
                app.handles.hitscore.figure = uifigure('Name', 'Hit Score');
                app.handles.hitscore.figure.Tag = 'holoShow.figures.hitscore';
                app.handles.hitscore.figure.NumberTitle = 'off';
                app.handles.hitscore.figure.DeleteFcn = createCallbackFcn(app, @dbFigureDeleteFcn, true);
                app.handles.hitscore.tiledlayout = tiledlayout(app.handles.hitscore.figure,2,1);

                app.handles.hitscore.axes(1) = nexttile(app.handles.hitscore.tiledlayout);
                app.handles.hitscore.axes(2) = nexttile(app.handles.hitscore.tiledlayout);
                app.handles.hitscore.plt.litpixel = stem(app.handles.hitscore.axes(1), nan, ...
                    'DisplayName', 'lit pixel', 'LineWidth', thisLineWidth);
                %                 app.handles.hitscore.axes(1).NextPlot = 'add';
                app.handles.hitscore.plt.multiscore = stem(app.handles.hitscore.axes(2), nan, ...
                    'DisplayName', 'multiscore', 'LineWidth', thisLineWidth, ...
                    'Color', [0.8500    0.3250    0.0980]);
                app.handles.hitscore.axes(2).NextPlot = 'add';
                app.handles.hitscore.plt.multiscore_old = stem(app.handles.hitscore.axes(2), nan, ...
                    'DisplayName', 'multiscore', 'LineWidth', thisLineWidth, ...
                    'Color', [0.4660    0.6740    0.1880]);

                %                 app.handles.hitscore.plt.litpixelSelected = stem(app.handles.hitscore.axes, nan, ...
                %                     'DisplayName', 'lit pixel', 'LineWidth', thisLineWidth);
                %                 app.handles.hitscore.plt.multiscoreSelected = stem(app.handles.hitscore.axes, nan, ...
                %                     'DisplayName', 'multiscore', 'LineWidth', thisLineWidth);
                %
                %                 app.handles.hitscore.axes(1).YScale = 'log';
                %                 xtickformat(app.handles.hitscore.axes, '%i')
                grid(app.handles.hitscore.axes(1), 'on');
                grid(app.handles.hitscore.axes(2), 'on');
                legend(app.handles.hitscore.axes(1))
                legend(app.handles.hitscore.axes(2))
            end

            app.handles.hitscore.plt.litpixel.XData = 1:app.thisrun.nbr_images;
            app.handles.hitscore.plt.litpixel.YData = app.db.LitPixel;
            app.handles.hitscore.plt.multiscore.XData = 1:app.thisrun.nbr_images;
            app.handles.hitscore.plt.multiscore.YData = app.db.MultiScore;
            app.handles.hitscore.plt.multiscore_old.XData = 1:app.thisrun.nbr_images;
            app.handles.hitscore.plt.multiscore_old.YData =  app.db.MultiOld;
            app.handles.hitscore.axes(1).XLim = app.handles.hitscore.plt.litpixel.XData([1,end]) + 0.5*[-1,1];
            app.handles.hitscore.axes(2).XLim = app.handles.hitscore.plt.litpixel.XData([1,end]) + 0.5*[-1,1];

        end

        
        function hfig = check_figure(app, varargin)
            hfig = [];
            hlevel = app.handles;
            for i=1:nargin-1
                if ~isfield(hlevel, varargin{i}), return; end
                hlevel = hlevel.(varargin{i});
            end
            hfig = hlevel;
        end
            

        function hfig = get_figure(app, tag)
            hfig = findobj(Tag=tag);
            if isempty(hfig) || ~isgraphics(hfig)
                hfig = figure;
                hfig.Tag = tag;
                hfig.Name = tag;
                app.handles.(tag).figure = hfig;
            end
            
        end
        
        function calc_rmean_range(app)
            lowerBound = max([app.handles.HPfrequency*app.handles.HPfiltering,50]);
            upperBound = max([app.handles.LPfrequency*app.handles.LPfiltering,511*~app.handles.LPfiltering]);
            app.handles.rmean.range = lowerBound:upperBound;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function holoShow_OpeningFcn(app, varargin)

            fprintf("Starting HoloShow ...\n\n")

            % Choose default command line output for holoShow
            [app.handles.sourcepath,~,~] = fileparts(mfilename('fullpath'));
            addpath(genpath(app.handles.sourcepath));

            % Load Config
            app.config_dropdown.ItemsData = 1:numel(app.config_dropdown.Items);
            app.loadUserConfig();
            app.loadExperimentConfig();

            % Change MATLAB default parameter
            app.changeMatlabDefaults();
            
            app.parameter.screensize = get(0, 'ScreenSize');
            
            % Check if figure objects already exist. If so, clear the
            % figures and preallocate graphic objects otherwise.
            app.handles.hologram.figure = findobj('Tag','holoShow.figures.hologram');
            app.handles.reconstruction.figure = findobj('Tag','holoShow.figures.reconstruction');
            app.handles.noise.figure = findobj('Tag','holoShow.figures.noise');
            app.handles.powerSpectrum.figure = findobj('Tag','holoShow.figures.powerSpectrum');
            if ~isempty(app.handles.hologram.figure)
                close(app.handles.hologram.figure)
            end
            if ~isempty(app.handles.reconstruction.figure)
                close(app.handles.reconstruction.figure);
            end
            if ~isempty(app.handles.noise.figure)
                close(app.handles.noise.figure);
            end
            if ~isempty(app.handles.powerSpectrum.figure)
                close(app.handles.powerSpectrum.figure);
            end
            app.handles.hologram.figure = gobjects(1);
            app.handles.reconstruction.figure = gobjects(1);
            app.handles.noise.figure = gobjects(1);
            app.handles.powerSpectrum.figure = gobjects(1);
            app.handles.db.figure = gobjects(1);
            app.handles.hitscore.figure = gobjects(1);

            % Preallocate and predefine variables
            app.data.recon.roi = [1 1 1023 1023];

            % app.handles.h5path.pnCCD = '/entry_1/image_1/data';
            % app.handles.h5path.bunch_id = '/entry_1/experiment_identifier';

            %             loadUserConfig(app, event)

            app.handles.h5path.pnCCD = '/entry_1/data_1/data';
            app.handles.h5path.mask = '/entry_1/data_1/mask';
            app.handles.h5path.bunch_id = '/entry_1/event/bunch_id';
            app.handles.h5path.hitscore_litpixel = '/entry_1/result_1/hitscore_litpixel';
            app.handles.h5path.multiscore_patterson = '/entry_1/result_1/multiscore_patterson';

            app.handles.pathname = fullfile(app.handles.sourcepath, 'data', 'test-data', 'LCLS_2014');
            
            if ~app.config.default
                if isfield(app.config, 'dataPath')
                    app.handles.pathname = app.config.dataPath;
                end
            end
            
            fn = dir(fullfile(app.handles.pathname, '*'));
            app.handles.filenames = {fn(~[fn.isdir]).name};

            if iscell(app.handles.filenames)
                app.handles.first_file = app.handles.filenames{1};
            else
                app.handles.first_file = app.handles.filenames;
            end
            [~,~,app.handles.ext] = fileparts(app.handles.first_file);
            if strcmp(app.handles.ext, '.cxi') || strcmp(app.handles.ext, '.h5')
                app.loadh5file();
            else
                set(app.filenames_listbox, 'Items', app.handles.filenames);
                app.handles.nbr_images = size(app.handles.filenames,2);
            end
            app.filenames_listbox.ItemsData = 1:app.handles.nbr_images;

            app.handles.partSwitch = get(get(app.part_buttongroup, 'SelectedObject'), 'Text');
            app.applyHologramCorrection_checkbox.Value = true;
            app.handles.af_method = 'variance';
            app.handles.crop_factor = round(str2double(get(app.crop_edit, 'Value')));

            app.handles.IF_filtering = get(app.intensity_filter_checkbox, 'Value');
            app.handles.IF_value = str2double(get(app.intensity_filter_edit, 'Value'));

            app.handles.smoothMask = app.smoothMask_checkbox.Value;
            app.handles.cut_center = 0;

            %             loadExperimentConfig(app, []);

            % load('config_holoShow_LCLS2014.mat'); % To change standard values use 'src/config/create_config.m' to change config file
            % for fn = fieldnames(config_file)'
            %    app.handles.(fn{1}) = config_file.(fn{1});
            % end


            scrSize = get(0,'ScreenSize');
            app.holoShow.Position(1) = scrSize(3)-app.holoShow.Position(3)-10;
            app.holoShow.Position(2) = 60;%[scrSize(4)-app.holoShow.Position(4)-60];
            app.holoShow.Color = [1 1 1];

            app.phase_slider.MajorTicks = [-1,-.5,0,.5,1]*app.range_edit.Value;
            app.phase_slider.MajorTickLabels = cellfun(@(x) sprintf('%d',x), num2cell(app.phase_slider.MajorTicks), 'UniformOutput',false);

            app.links.clusterradius = linkprop([app.clusterradius_edit, app.clusterradius_slider], 'Value');
            app.links.phase = linkprop([app.phase_edit, app.phase_slider], 'Value');
            app.links.scattRatio = linkprop([app.scattRatio_edit, app.scattRatio_slider], 'Value');

            app.handles.rmean.bins = [];
            app.calc_rmean_range();

            select_pushbutton_Callback(app, []);
        end

        % Button pushed function: SNR_pushbutton
        function SNR_pushbutton_Callback(app, event)

            fprintf('SNR button pressed ... \n')

            %             recon = app.handles.recon(app.data.recon.roi(2):app.data.recon.roi(2)+app.data.recon.roi(4),app.data.recon.roi(1):app.data.recon.roi(1)+app.data.recon.roi(3));
            %             FRecon = fftshift(fft2(recon,1024,1024)/length(recon));
            %             FRecon = abs(FRecon).^2;

            %             [radialFRecon, radii] = rmean(FRecon, app.handles.rmean.range, [], app.handles.rmean.bins);
            dn = app.handles.lambda*app.handles.detDistance/75e-6*1e9/2;
            dq = 2*pi/dn;

            % Signal-to-Noise Ratio
            if ~isfield(app.data,'noise')
                app.getNoiseFromArea();
            end

            recon = app.data.recon.roiSignal;
            FRecon = app.data.recon.roiSpectrum2D; %#ok<NASGU> 
            noise2D = app.data.noise.spectrum2D; %#ok<NASGU> 
            radialFRecon = app.data.recon.roiSpectrum; %#ok<NASGU> 
            noise = app.data.noise.spectrum; %#ok<NASGU> 
            SNR = app.data.snr.spectrum;
            radii = app.data.snr.radii;

            if ~isfield(app.handles, 'snr') || ~isfield(app.handles.snr, 'figure') || ~isgraphics(app.handles.snr.figure)
                app.handles.snr.figure = figure;
                app.handles.snr.figure.Tag = 'holoShow.snr.figure';
                app.handles.snr.figure.BusyAction = 'cancel';
                app.handles.snr.axes(1) = subplot(3,1,1, 'parent', app.handles.snr.figure);
                app.handles.snr.axes(2) = subplot(3,1,2, 'parent', app.handles.snr.figure);
                app.handles.snr.axes(3) = subplot(3,1,3, 'parent', app.handles.snr.figure);
                % CREATE SAVE BUTTON
                app.handles.snr.saveButton = uicontrol('parent', app.handles.snr.figure, 'style', 'pushbutton');
                app.handles.snr.figure.Units = 'pixels';
                fp = app.handles.snr.figure.Position;
                app.handles.snr.saveButton.Position = [fp(3)-130 fp(4)-37 100 22];
                app.handles.snr.saveButton.String = 'save';
                app.handles.snr.saveButton.Callback = createCallbackFcn(app, @saveSnrFigureButtonBushedFcn, true);
            end

            % SNR
            app.handles.snr.axes(1).NextPlot = 'replace';
            semilogy(app.handles.snr.axes(1), radii, SNR, '-', 'DisplayName', 'SNR'); hold on;
            app.handles.snr.axes(1).NextPlot = 'add';
            semilogy(app.handles.snr.axes(1), radii([1,end]), [1,1]*1, '--', 'DisplayName', 'SNR=1');
            semilogy(app.handles.snr.axes(1), radii([1,end]), [1,1]*3, '--', 'DisplayName', 'SNR=3');
            semilogy(app.handles.snr.axes(1), radii([1,end]), [1,1]*5, '--', 'DisplayName', 'SNR=5');

            legend(app.handles.snr.axes(1))
            xlim(app.handles.snr.axes(1), radii([1,end]))

            grid(app.handles.snr.axes(1), 'on');
            app.handles.snr.axes(1).XTick=0:50:500;
            app.handles.snr.axes(1).XTickLabel=round(dn./(0:50:500));
            xlabel(app.handles.snr.axes(1), 'resolution in nm')

            [~, maxIdx] = max(SNR);
            try
                diff = SNR(maxIdx:end) - 5;
                idx = find(diff < eps, 1) + maxIdx-1;
                px3 = mean(radii(idx-1:idx));
                py3 = mean(SNR(idx-1:idx));
                plot(app.handles.snr.axes(1), px3, py3, 'ro', 'MarkerSize', 10);
                title(app.handles.snr.axes(1), sprintf('SNR = 5 at ~ %1.fnm', dn/px3));
            end
            hold(app.handles.snr.axes(1), 'off');

            % Contrast
            %             C = (radialFRecon(radii)-app.handles.noiseSpec(radii))./radialFRecon(radii);
            C = (app.data.recon.roiSpectrum - app.data.noise.spectrum) ./ (app.data.recon.roiSpectrum + app.data.noise.spectrum);
            app.handles.snr.axes(2).NextPlot = 'replace';
            plot(app.handles.snr.axes(2), radii, C);
            app.handles.snr.axes(2).NextPlot = 'add';
            plot(app.handles.snr.axes(2), radii([1,end]), (1-0.153)*[1,1]);
            xlim(app.handles.snr.axes(2), radii([1,end]));
            ylim(app.handles.snr.axes(2), [0,1]);
            grid(app.handles.snr.axes(2), 'on');
            app.handles.snr.axes(2).XTick=0:50:500;
            app.handles.snr.axes(2).XTickLabel=round(dn./app.handles.snr.axes(2).XTick);
            title(app.handles.snr.axes(2), 'Contrast');
            try
                diff = C(maxIdx:end)-(1-0.153);
                idx = find(diff < eps, 1) + maxIdx-1;
                px3 = radii(idx);
                py3 = C(idx);
                plot(app.handles.snr.axes(2), px3, py3, 'ro', 'MarkerSize', 10);
                title(app.handles.snr.axes(2), sprintf('Contrast = 0.847 at ~ %1.fnm', dn/px3));
            end

            app.data.contrast.radii = radii;
            app.data.contrast.q = dq * radii;
            app.data.contrast.res = dn ./ radii;
            app.data.contrast.contrast = C;

            %             app.handles.SNR2D=(FRecon./app.handles.noiseSpec2D).^2;
            app.data.snr.spectrum2D = app.data.recon.roiSpectrum2D ./...
                app.data.noise.spectrum2D;


            % FOURIER RING CORRELATION
            superpixelsize = 2;
            inReal = 1;
            reconCutout = recon(1:2*(floor(end/2)),1:2*(floor(end/2)));
            
            if inReal
                frcInput = reconCutout;
            else
                fftsize = 2^nextpow2(max(size(reconCutout))); %#ok<UNRCH> 
                frcInput = fftshift(fft2(fftshift(reconCutout), fftsize, fftsize));
            end
            [FRCout, twoSigma, halfBit] = FRC(frcInput, 'realspace', inReal, 'superpixelsize', superpixelsize, 'ringwidth', 2);
            xFRC = 1:length(FRCout);

            
            app.handles.snr.axes(3).NextPlot = 'replace';
            plot(app.handles.snr.axes(3), xFRC, FRCout, 'DisplayName', 'FRC');
            app.handles.snr.axes(3).NextPlot = 'add';
            plot(app.handles.snr.axes(3), xFRC, twoSigma, 'DisplayName', '2\sigma criterion');
            plot(app.handles.snr.axes(3), xFRC, halfBit, 'DisplayName', '1/2 bit criterion');
            plot(app.handles.snr.axes(3), xFRC([1,end]), 0.5*[1,1], 'DisplayName', '0.5 criterion');
            grid(app.handles.snr.axes(3), 'on');
            app.handles.snr.axes(3).XTickLabel=round(dn./( app.handles.snr.axes(3).XTick/max(app.handles.snr.axes(3).XTick)*512/superpixelsize ) );
            title(app.handles.snr.axes(3), 'Fourier Ring Correlation');
            legend(app.handles.snr.axes(3))
            ylim(app.handles.snr.axes(3), [0, 1.05])
            xlabel(app.handles.snr.axes(3), 'resolution in nm')
            try
                [~, maxIdx] = max(FRCout);
                %                 minFreq=3; hold on;
                diff=FRCout(maxIdx:end) - 0.5;
                idx = find(diff < eps, 1) + maxIdx-1;
                px3 = mean( xFRC(idx-1:idx) );
                py3 = mean( FRCout(idx-1:idx) );
                plot(app.handles.snr.axes(3), px3, py3, 'ro', 'MarkerSize', 10, 'DisplayName', '');
                title(app.handles.snr.axes(3), sprintf('FRC resolution ~ %1.fnm', dn/(px3/max(app.handles.snr.axes(3).XTick)*512/superpixelsize)));
            end
            hold(app.handles.snr.axes(3), 'off');

            app.data.frc.x = xFRC;
            app.data.frc.res = dn./(app.data.frc.x/app.data.frc.x(end)*512/superpixelsize);
            app.data.frc.q = dq * (app.data.frc.x/app.data.frc.x(end)*512/superpixelsize);
            app.data.frc.frc = FRCout;
            app.data.frc.twoSigma = twoSigma;
            app.data.frc.halfBit = halfBit;

        end

        % Button pushed function: abort_pushbutton
        function abort_pushbutton_Callback(app, event)
            app.abort_pushbutton.UserData = true;
        end

        % Value changed function: autofocus_popupmenu
        function autofocus_popupmenu_Callback(app, event)
            app.handles.af_method = app.autofocus_popupmenu.Value;
        end

        % Button pushed function: center_pushbutton
        function center_pushbutton_Callback(app, event)
            [columnsToShift, rowsToshift, app.handles.phaseOffset] = find_center(app);
%             app.handles.hologram.masked = simpleshift(app.handles.hologram.masked, -[rowsToshift,columnsToShift] );
%             app.handles.mask = simpleshift(app.handles.mask, -[rowsToshift,columnsToShift] );
            app.handles.xcenter = app.handles.xcenter + columnsToShift;
            app.handles.ycenter = app.handles.ycenter + rowsToshift;
            refresh_hologram(app, event);
        end

        % Button pushed function: chooseCC_pushbutton
        function chooseCC_pushbutton_Callback(app, event)
    
%             colormap(app.handles.reconAxes, r2b);
%             newrect = round(getrect(app.handles.reconAxes));
            xlims = app.handles.reconAxes.XLim;
            ylims = app.handles.reconAxes.YLim;
            newrect = round([xlims(1), ylims(1), diff(xlims), diff(ylims)]);
            
            colormap(app.handles.reconAxes, gray);
            if app.squareROI_checkbox.Value
                newrect(3) = min([newrect(3),newrect(4)]);
                newrect(4) = min([newrect(3),newrect(4)]);
            end            

            app.data.recon.roi(1) = max([1, app.data.recon.roi(1)+newrect(1)-1]);
            app.data.recon.roi(2) = max([1, app.data.recon.roi(2)+newrect(2)-1]);
            app.data.recon.roi(3) = min([size(app.handles.recon,1)-newrect(1)-1, newrect(3)]);
            app.data.recon.roi(4) = min([size(app.handles.recon,2)-newrect(2)-1, newrect(4)]);
            app.handles.reconAxes.XLim = [1, 1+app.data.recon.roi(3)];
            app.handles.reconAxes.YLim = [1, 1+app.data.recon.roi(4)];
            fprintf('ROI = [%.0f, %.0f, %.0f, %.0f]\n', app.data.recon.roi(1), app.data.recon.roi(2), app.data.recon.roi(1)+app.data.recon.roi(3), app.data.recon.roi(2)+app.data.recon.roi(4));
            refreshImage(app, event)
%             drawnow;
        end

        % Button pushed function: choose_runs_pushbutton
        function choose_runs_pushbutton_Callback(app, event)
            %% ONLY IMPLEMENTED FOR FLASH2017 ATM
            if ~isfield(app.handles, 'files') || ~isfield(app.handles.files, 'selected') || isnumeric(app.handles.files.selected)
                app.handles.files.selected = string(app.handles.first_file); 
            end
%             Default = sprintf('"%s" ', fullfile(app.handles.pathname, app.handles.files.selected{:}));
%             Default(end) = [];  % Remove trailing space, just to be lean
            Default = fullfile(app.handles.pathname, app.handles.files.selected(1));
            [selected_files, selected_path] = uigetfile('*.dat;*.mat;*.h5;*.cxi',...
                'select hologram files', Default, 'MultiSelect','On');
            
            if isequal(selected_files, 0), return; end
            app.handles.files.selected = string(selected_files);
            app.handles.pathname = selected_path;
            app.handles.first_file = char(app.handles.files.selected(1));

            for file = app.handles.files.selected
                %             set(app.filenames_listbox, 'Value', 1); % set selection to first entry
    %             [newFileNames, newPath] = uigetfile('*.dat;*.mat;*.h5;*.cxi',...
    %                 'select hologram files', fullfile(app.handles.pathname, app.handles.first_file), 'MultiSelect','On'); % get list of files and path
                newFileNames = file{:};
%                 newPath = app.handles.pathname;
        
%                 if iscell(newFileNames)
%                     app.handles.first_file = newFileNames{1};
%                 else
%                     app.handles.first_file = newFileNames;
%                 end
                app.handles.first_file = file{:}
                [~,~,app.handles.ext] = fileparts(app.handles.first_file);
    
%                 app.handles.pathname = newPath;
    
                if strcmp(app.handles.ext, '.cxi') || strcmp(app.handles.ext, '.h5')
                    app.saveDB();
                    app.loadh5file();
                else
                    app.filenames_listbox.Items = newFileNames;
                    app.handles.nbr_images = size(newFileNames,2);
                end
                app.filenames_listbox.ItemsData = 1:app.handles.nbr_images;
                app.handles.fileIndex = 1;
                app.filenames_listbox.Value = app.handles.fileIndex;
    
                app.handles.filenames = newFileNames;
    
                select_hologram(app, event); % GRAB HOLOGRAM

                drawnow; 

                wholeRun_pushbutton_Callback(app, event)


% % %             app.handles.runfolders = uipickfiles;% pick run folders
% % %             for run=1:length(app.handles.runfolders)
% % %                 try
% % %                     app.handles.pathname = app.handles.runfolders{run};
% % %                     filelist = ls(app.handles.pathname);
% % %                     app.handles.filenames=[];
% % %                     for i=3:size(filelist,1)
% % %                         app.handles.filenames{i}=strrep(filelist(i,:),' ','');
% % %                     end
% % % 
% % %                     set(app.filenames_listbox, 'String', app.handles.filenames);
% % %                 catch
% % %                     warning('something went wrong ...')
% % %                     continue
% % %                 end
% % % 
% % %                 for i = 1:app.handles.nbr_images
% % %                     try
% % %                         app.handles.fileIndex = i;
% % %                         %                         set(app.filenames_listbox, 'Value', app.handles.fileIndex);
% % %                         app.filenames_listbox.Value = app.filenames_listbox.Items{app.handles.fileIndex};
% % %                         try
% % %                             select_hologram(app, event);
% % %                         catch
% % %                             continue
% % %                         end
% % %                         app.handles.centroids = findCrossCorrelations(app.handles.recon);
% % %                         if size(app.handles.centroids,1)==1
% % %                             continue
% % %                         end
% % %                         maxPhase = app.phase_slider.Limits(2);
% % %                         app.handles.foci = find_foci(app.handles.hologram.masked, app.handles.lambda, app.handles.detDistance, app.handles.centroids, ...
% % %                             'z_start', -maxPhase, 'z_end', maxPhase, 'steps', 20, 'use_gpu', true, 'show_results', true, 'crop_factor', app.handles.crop_factor);
% % %                     catch
% % %                         fprintf('failed on %s !!!\n', app.handles.currentFile);
% % %                     end
% % %                 end
% % %             end

            end
        end

        % Value changed function: cm_checkbox
        function cm_checkbox_Callback(app, event)

            app.handles.do_cm = get(app.cm_checkbox, 'Value');
            refresh_hologram(app, event);

        end

        % Selection changed function: colormap_buttongroup
        function colormap_buttongroup_SelectionChangedFcn(app, event)
            app.handles.colormap = app.colormap_buttongroup.SelectedObject.Text;
            set_colormap(app.handles.colormap, app.handles.reconAxes);
            % show_recon(app, event);
        end

        % Value changed function: command_edit
        function command_edit_Callback(app, event)

            cmStr = get(app.command_edit, 'Value');
            eval(char(cmStr));

        end

        % Callback function
        function command_window_pushbutton_Callback(app, event)


        end

        % Value changed function: config_dropdown
        function config_dropdown_Callback(app, event)
            app.config.experiment = app.config_dropdown.Items{app.config_dropdown.Value};
            app.loadExperimentConfig();
            refresh_hologram(app, event);
            refreshImage(app, event);
        end

        % Value changed function: crop_checkbox
        function crop_checkbox_Callback(app, event)

            app.handles.crop_image = get(app.crop_checkbox, 'Value');
            crp_fct = round(str2double(get(app.crop_edit, 'Value')));
            if crp_fct > 1 && app.handles.crop_image
                app.handles.crop_factor = crp_fct;
            else
                app.handles.crop_factor = 1;
            end

        end

        % Value changed function: crop_edit
        function crop_edit_Callback(app, event)

            app.handles.crop_image = get(app.crop_checkbox, 'Value');
            crp_fct = round(str2double(get(app.crop_edit, 'Value')));
            if crp_fct > 1 && app.handles.crop_image
                app.handles.crop_factor = crp_fct;
            else
                app.handles.crop_factor = 1;
            end

        end

        % Value changed function: cut_center_checkbox
        function cut_center_checkbox_Callback(app, event)

            if app.cut_center_checkbox.Value
                app.handles.cut_center = str2double(app.cut_center_edit.Value);
            else
                app.handles.cut_center = 0;
            end
            refresh_hologram(app, event);

        end

        % Value changed function: cut_center_edit
        function cut_center_edit_Callback(app, event)

            if app.cut_center_checkbox.Value
                app.handles.cut_center = str2double(app.cut_center_edit.String);
            else
                app.handles.cut_center = 0;
            end
            refresh_hologram(app, event);

        end

        % Value changed function: decon_checkbox
        function decon_checkbox_Callback(app, event)
            app.calc_rmean_range();
            fprintf('deconvolving ... \n');
            if ~isfield(app.data,'hologram')
                hologram = abs(app.handles.hologram.propagated);
                hologram = hologram/mean(size(hologram));
                [spectrumHolo, ~] = rmean(hologram, app.handles.rmean.range, [], app.handles.rmean.bins);
                app.data.hologram.spectrum = spectrumHolo;
            end
            if ~isfield(app.data.hologram,'spectrum')
                hologram = abs(app.handles.hologram.propagated);
                hologram = hologram/mean(size(hologram));
                [spectrumHolo, ~] = rmean(hologram, app.handles.rmean.range, [], app.handles.rmean.bins);
                app.data.hologram.spectrum = spectrumHolo;
            end
            if ~isfield(app.data.recon,'spectrum')
                app.data.recon.spectrum = nan;
            end

            if app.decon_checkbox.Value
                app = clusterDeconvolution(app, event);
%                 app = DEFTH(app, event);
%                 app.handles.hologram.propagated = propagateHologram(app.handles.hologram.deconvoluted, app.handles.phase, app.handles.lambda, app.handles.detDistance, app.handles.cut_center);
                %                 propagateHologram(wavefield, propagation_dist, wavelength, detector_dist, propagation_method, shifted, px_size)
            else
                app.handles.hologram.propagated = propagateHologram(app.handles.hologram.masked, app.handles.phase, app.handles.lambda, app.handles.detDistance, app.handles.cut_center);
            end
            app.handles.recon = ift2(app.handles.hologram.propagated);
            % app.handles.hologram.propagated = app.handles.hologram.propagated.*exp(1i*app.handles.phaseOffset);

            deconData = part_and_scale(app.handles.recon(app.data.recon.roi(2):app.data.recon.roi(2)+app.data.recon.roi(4),app.data.recon.roi(1):app.data.recon.roi(1)+app.data.recon.roi(3)),...
                app.handles.partSwitch);
            if ~isfield(app.handles, 'deconFigure') || ~isgraphics(app.handles.deconFigure)
                app.handles.deconFigure = figure;
                app.handles.deconFigure.Tag = 'holoShow.decon.figure';
                app.handles.deconFigure.Name = 'holoShow.decon.figure';
                app.handles.deconFigure.BusyAction = 'cancel';
                app.handles.deconAxes = axes(app.handles.deconFigure);
                app.handles.deconImage = imagesc(app.handles.deconAxes, deconData);
                axis(app.handles.deconAxes, 'image')
                app.handles.deconFigure.Position(1:2) = [app.handles.reconstruction.figure.Position(1)+app.handles.reconstruction.figure.Position(3),app.parameter.screensize(4)-app.handles.hologram.figure.Position(4)];
%                 app.handles.deconFigure.Position(2) = app.handles.deconFigure.Position(2)-80;
            else
                app.handles.deconImage.CData = deconData;
            end

            app.handles.deconAxes.Title.String = sprintf('cluster radius = %.1f', app.handles.clusterradius);            
            app.handles.db.uitable.Data.clusterSize(app.handles.fileIndex) = app.handles.clusterradius;
            app.handles.db.uitable.Data.scattRatio(app.handles.fileIndex) = app.handles.scat_ratio;
            app.handles.db.uitable.Data.deltaZ(app.handles.fileIndex) = app.handles.phase;
            fprintf('\t\t\tdone! \n')

        end

        % Value changed function: detDist_edit
        function detDist_edit_Callback(app, event)

            app.handles.detDistance = str2double(app.detDist_edit.Value');
            refresh_hologram(app, event);

        end

        % Value changed function: filenames_listbox
        function filenames_listbox_Callback(app, event)
            app.handles.fileIndex = app.filenames_listbox.Value; % get index of selection
            %             [~, app.handles.fileIndex] = ismember(app.filenames_listbox.Value, app.filenames_listbox.Items);
            select_hologram(app, event); % GRAB HOLOGRAM
        end

        % Button pushed function: findCC_pushbutton
        function findCC_pushbutton_Callback(app, event)
%             % LCLS2014 VALUES
%             app.parameter.find_cc.mind_dist = 75;
%             app.parameter.find_cc.int_thresh = 5;
%             app.parameter.find_cc.r_ignored = 75;
%             app.parameter.find_cc.crop_factor = app.handles.crop_factor;
            app.parameter.find_cc.show_img = true;
            app.parameter.find_cc.mind_dist = 100;
            app.parameter.find_cc.int_thresh = 7;
            app.parameter.find_cc.r_ignored = 100;
            app.parameter.find_cc.r_dilate = 10;
            app.parameter.find_cc.r_erode = 10;
            app.parameter.find_cc.fudge_factor = 1;
            app.parameter.find_cc.crop_factor = 1;
            app.parameter.find_cc.min_size = 500;
            app.parameter.find_cc.roi_pixels = 80;

            app.handles.centroids = findCrossCorrelations(app, app.handles.hologram.masked, app.parameter.find_cc);
            if isequal(app.handles.centroids,[0,0])
                fprintf('\t\t\t\tNO CROSS CORRELATION FOUND!\n')
            end

        end

        % Button pushed function: find_decon_pushbutton
        function find_decon_pushbutton_Callback(app, event)

            fprintf('looking for cluster radius ...');
            app.handles.hologram.propagated = propagateHologram(abs(app.handles.hologram.masked), app.handles.phase, app.handles.lambda, app.handles.detDistance, app.handles.cut_center);
            app.handles.hologram.propagated = app.handles.hologram.propagated.*exp(1i*app.handles.phaseOffset);

            app.handles.clusterradius = findDeconvolution(app.handles);
            app.clusterradius_edit.Value = app.handles.clusterradius;
            decon_checkbox_Callback(app, event)

            fprintf('done! \n')

        end

        % Button pushed function: find_phase_pushbutton
        function find_phase_pushbutton_Callback(app, event)
            chooseCC_pushbutton_Callback(app, event)
            maxPhase = app.phase_slider.Limits(2);
            show_autofocus = true;
            if ~isfield(app.handles.hologram,'deconvoluted'), app.handles.hologram.deconvoluted = zeros(size(app.handles.hologram.masked), 'like', app.handles.hologram.masked); end
            holodata = iif(app.decon_checkbox.Value, app.handles.hologram.deconvoluted, app.handles.hologram.masked);
            focussteps = 50;
            app = find_focus(app, holodata, app.handles.lambda, app.handles.detDistance, app.data.recon.roi, -maxPhase, maxPhase, focussteps, app.handles.af_method, show_autofocus, app.handles.gpu, 0, 1);
%             app = find_focus_3d(app, holodata, app.handles.lambda, app.handles.detDistance, app.data.recon.roi, -maxPhase, maxPhase, 100, app.handles.af_method, show_autofocus, app.handles.gpu, 0, 1);
            app.phase_slider.Value = app.handles.phase;
            app.phase_edit.Value = round(app.handles.phase);
            refreshImage(app, event);

        end

        % Button pushed function: focusCC_pushbutton
        function focusCC_pushbutton_Callback(app, event)

            findCC_pushbutton_Callback(app, event)

            if ~isequal(app.handles.centroids,[0,0])
                maxPhase = app.phase_slider.Limits(2);
                fprintf('looking for foci...')
                app.parameter.find_foci.steps = 200;
                app = find_foci(app, app.handles.hologram.masked, app.handles.lambda, app.handles.detDistance, app.handles.centroids, ...
                    'z_start', -maxPhase, 'z_end', maxPhase, 'steps', app.parameter.find_foci.steps, 'use_gpu', app.handles.gpu, ...
                    'show_results', true, ...
                    'show_focusing', false, ...
                    'crop_factor', app.handles.crop_factor);
                fprintf(' done!\n')

%                 try
                    if get(app.save_checkbox, 'Value') && size(app.handles.centroids,1)>0
                        savedir = fullfile(app.handles.pathname,'analysis');
                        if ~exist(savedir,'dir'); mkdir(savedir); end

                        savename = app.handles.currentFile(1:end-3);
                        if strcmp(app.handles.ext, '.cxi') || strcmp(app.handles.ext, '.h5')
                            savename = sprintf('%s_%08d', savename, app.handles.entrylist(app.handles.fileIndex));
                        end
                        savesuffix = '.png';

                        exportgraphics(app.handles.foci_results.figure, fullfile(savedir, [savename, savesuffix]));
                        app.handles.hologramI.CData = app.handles.hologram.masked .* app.handles.hardmask;
                        exportgraphics(app.handles.hologram.figure, fullfile(savedir, [savename, '_Hologram', savesuffix]));
                        exportgraphics(app.handles.reconstruction.figure, fullfile(savedir, [savename, '_Patterson', savesuffix]));
                        fprintf('Saved focused cc: %s\n', fullfile(savedir, [savename, savesuffix]))
%                         figure(34556);
%                         subplot(121); imagesc(log10(abs(app.handles.hologram.masked)),[1, 4.2]); axis square; colormap imorgen; colorbar;
%                         subplot(122); imagesc(app.handles.reconI.CData); colorbar; axis square; colormap gray;
%                         if strcmp(app.handles.ext, '.cxi') || strcmp(app.handles.ext, '.h5')
%                             print(34556, fullfile(app.handles.sourcepath, 'analysis', [app.handles.currentFile(1:end-3), '_', num2str(app.handles.entrylist(app.handles.fileIndex)), 'H.png']),'-dpng');
%                         else
%                             print(34556, fullfile(app.handles.sourcepath, 'analysis', [app.handles.currentFile(1:end-3), '.png']),'-dpng');
%                         end
                    end
%                 catch
%                     fprintf('saving focused CCs did not work!\n')
%                 end

            end

        end

        % Button pushed function: goodShot_pushbutton
        function goodShot_pushbutton_Callback(app, event)

            if iscell(app.filenames_listbox.Items)
                shotname = app.filenames_listbox.Items{app.filenames_listbox.Value};
            else
                shotname = app.filenames_listbox.Value;
                warning("Listbox Items are not a cell array. Taking Listbox Value as shotname. This might be a non-unique identifier!")
            end

            anapath = fullfile(app.handles.sourcepath, 'analysis','good-shots');
            goodShotsFile = fullfile(anapath,'good-shots.txt');
            timeStr = string(datetime('now','Format','yyyy-MM-dd_HH:mm:ss'));

            if ~exist(anapath, 'dir')
                mkdir(anapath)
            end
            if ~exist(goodShotsFile, "file")
                fprintf('Creating file %s\n', goodShotsFile)
                fileID=fopen(goodShotsFile,'a');
                fprintf(fileID,'filename, \tshotname, \ttimestamp\n');
            else
                fileID=fopen(goodShotsFile,'a');
            end
            fprintf(fileID,'%s, \t%s, \t%s\n',app.handles.currentFile,shotname,timeStr);
            fclose(fileID);
        end

        % Value changed function: highpass_checkbox
        function highpass_checkbox_Callback(app, event)
            app.handles.HPfiltering = app.highpass_checkbox.Value;
            app.handles.HPfrequency = round(str2double(app.highpass_edit.Value));
            refresh_hologram(app, event);
        end

        % Value changed function: highpass_edit
        function highpass_edit_Callback(app, event)
            app.handles.HPfiltering = get(app.highpass_checkbox,'value');
            app.handles.HPfrequency = round(str2double(app.highpass_edit.Value));
            refresh_hologram(app, event);
        end

        % Close request function: holoShow
        function holoShow_CloseRequestFcn(app, event)
            try close(app.handles.reconstruction.figure);end
            try close(app.handles.hologram.figure);end
            try close(app.handles.noise.figure);end
            try close(app.handles.powerSpectrum.figure);end
            try close(app.handles.db.figure);end
            delete(app);
        end

        % Key press function: holoShow
        function holoShow_KeyPressFcn(app, event)

            switch event.Key
                case {'uparrow'}
                    if isgraphics(app.handles.db.figure)
                        app.handles.fileIndex = app.handles.fileIndex-1;
                        scroll(app.handles.db.uitable, 'row', app.handles.fileIndex);
                    else, scrollHologram(app,event,-1); 
                    end
                case {'downarrow'}
                    if isgraphics(app.handles.db.figure)
                        app.handles.fileIndex = app.handles.fileIndex+1;
                        scroll(app.handles.db.uitable, 'row', app.handles.fileIndex);
                    else, scrollHologram(app,event,1); 
                    end
                case {'leftarrow'}
                    app.minusphase_pushbutton_Callback(event);
                case {'rightarrow'}    
                    app.plusphase_pushbutton_Callback(event);
                case {'l'}
                    load_pushbutton_Callback(app,event)
                case {'c'}
                    chooseCC_pushbutton_Callback(app,event)
                case {'r'}
                    reset_pushbutton_Callback(app,event)
                case {'f'}
                    find_phase_pushbutton_Callback(app,event)
                case {'p'}
                    powerSpec_pushbutton_Callback(app,event)
                case {'n'}
                    noise_pushbutton_Callback(app,event)
                case 'm'
                    figure(app.handles.hologram.figure)
                case {'s'}
                    SNR_pushbutton_Callback(app,event)
                case {'k'}
                    knife_edge_pushbutton_Callback(app,event)
                case {'d'}
                    app.decon_checkbox.Value = ~app.decon_checkbox.Value;
                    decon_checkbox_Callback(app,event)
                case {'e'}
                    center_pushbutton_Callback(app,event)
                case {'h'}
                    shifts_pushbutton_Callback(app,event)
                case {'g'}
                    goodShot_pushbutton_Callback(app,event)
                case 'a'
                    focusCC_pushbutton_Callback(app,event)
                case {'escape'}
                    abort_pushbutton_Callback(app,event)
                case {'b'}
                    app.save_data_barmherzig();
                case {'v'}
                        fprintf('\n\n\n\n');
                case{'delete'}
                    app.handles.phase = 0;
                    refresh_hologram(app, event);
                otherwise
                    return
            end

        end

        % Value changed function: applyHologramCorrection_checkbox
        function applyHologramCorrection_checkbox_Callback(app, event)

            refresh_hologram(app, event);

        end

        % Value changed function: intensity_filter_checkbox
        function intensity_filter_checkbox_Callback(app, event)

            app.handles.IF_value = str2double(app.intensity_filter_edit.Value);
            app.handles.IF_filtering = app.intensity_filter_checkbox.Value;
            refresh_hologram(app, event);

        end

        % Value changed function: intensity_filter_edit
        function intensity_filter_edit_Callback(app, event)
            app.handles.IF_filtering = get(app.intensity_filter_checkbox, 'Value');
            app.handles.IF_value = str2double(get(app.intensity_filter_edit, 'Value'));
            refresh_hologram(app, event);

        end

        % Button pushed function: knife_edge_pushbutton
        function knife_edge_pushbutton_Callback(app, event)

            axes(app.handles.reconAxes);
            %             figure(23446)
            [xx,yy,app.handles.profile] = improfile;
            try
                delete(app.handles.profileLine);
            end
            app.handles.profileLine = line([xx(1),xx(end)],[yy(1),yy(end)],'Color','r','LineWidth',2);

            figure(10);
            plot(app.handles.profile);

        end

        % Value changed function: load_mask_checkbox
        function load_mask_checkbox_Callback(app, event)
            app.handles.load_mask = app.load_mask_checkbox.Value;
            refresh_hologram(app, event);
        end

        % Button pushed function: load_pushbutton
        function load_pushbutton_Callback(app, event)

            %             set(app.filenames_listbox, 'Value', 1); % set selection to first entry
            [newFileNames, newPath] = uigetfile('*.dat;*.mat;*.h5;*.cxi',...
                'select hologram files', fullfile(app.handles.pathname, app.handles.first_file), 'MultiSelect','On'); % get list of files and path

            if isequal(newFileNames, 0) % selection aborted
                return
            end

            if iscell(newFileNames)
                app.handles.first_file = newFileNames{1};
            else
                app.handles.first_file = newFileNames;
            end
            [~,~,app.handles.ext] = fileparts(app.handles.first_file);

            app.handles.pathname = newPath;

            if strcmp(app.handles.ext, '.cxi') || strcmp(app.handles.ext, '.h5')
                app.saveDB();
                app.loadh5file();
            else
                app.filenames_listbox.Items = newFileNames;
                app.handles.nbr_images = size(newFileNames,2);
            end
            app.filenames_listbox.ItemsData = 1:app.handles.nbr_images;
            app.handles.fileIndex = 1;
            app.filenames_listbox.Value = app.handles.fileIndex;

            app.handles.filenames = newFileNames;

            select_hologram(app, event); % GRAB HOLOGRAM

        end

        % Value changed function: log_checkbox
        function log_checkbox_Callback(app, event)

            %             show_recon(app, event);
            refreshImage(app, event);

        end

        % Value changed function: lowpass_checkbox
        function lowpass_checkbox_Callback(app, event)

            app.handles.LPfiltering = get(app.lowpass_checkbox,'value');
            app.handles.LPfrequency = round(str2double(get(app.lowpass_edit,'Value')));
            refresh_hologram(app, event);

        end

        % Value changed function: lowpass_edit
        function lowpass_edit_Callback(app, event)

            app.handles.LPfiltering = get(app.lowpass_checkbox,'value');
            app.handles.LPfrequency = round(str2double(get(app.lowpass_edit,'Value')));
            refresh_hologram(app, event);

        end

        % Callback function
        function makeGIF_checkbox_Callback(app, event)

            sprintf('removed feature!')
        end

        % Value changed function: max_edit
        function max_edit_Callback(app, event)

            app.handles.maxScale = str2double(get(app.max_edit, 'Value'));
            if get(app.scale_checkbox, 'Value')
                axes(app.handles.reconAxes);
                caxis([app.handles.minScale, app.handles.maxScale]);
            end

        end

        % Value changed function: min_edit
        function min_edit_Callback(app, event)

            app.handles.minScale = str2double(get(app.min_edit, 'Value'));
            if get(app.scale_checkbox, 'Value')
                axes(app.handles.reconAxes);
                caxis([app.handles.minScale, app.handles.maxScale]);
            end

        end

        % Button pushed function: minus1_radius_pushbutton
        function minus1_radius_pushbutton_Callback(app, event)
            update_clusterradius(app,event,app.handles.clusterradius-1)
        end

        % Button pushed function: minusphase_pushbutton
        function minusphase_pushbutton_Callback(app, event)

            app.handles.phase = app.phase_edit.Value - 100;
            app.phase_edit.Value = app.handles.phase;
            app.phase_slider.Value = app.handles.phase;
            refreshImage(app, event);

        end

        % Button pushed function: next_pushbutton
        function next_pushbutton_Callback(app, event)
            scrollHologram(app,event,1)
        end

        % Button pushed function: noise_pushbutton
        function noise_pushbutton_Callback(app, event)
            fprintf('Getting noise from Patterson Map. Please choose noise area from figure.\n')
            app.getNoiseFromArea();

        end

        % Button pushed function: parameter_window_pushbutton
        function parameter_window_pushbutton_Callback(app, event)

            app.handles.parameter_figure = findobj('Tag','parameter_figure');
            if ifgraphics(app.handles.parameter_figure)
                figure(app.handles.paramter_gui)
            else
                app.handles.paramter_gui = parameter_window;
            end

        end

        % Selection changed function: part_buttongroup
        function part_buttongroup_SelectionChangedFcn(app, event)
            app.handles.partSwitch = app.part_buttongroup.SelectedObject.Text;
            refreshImage(app, event);
            %             show_recon(app, event);
        end

        % Value changed function: phase_slider
        function phase_slider_Callback(app, event)
            refreshPhase(app, event);
        end

        % Button pushed function: plus1_radius_pushbutton
        function plus1_radius_pushbutton_Callback(app, event)
            update_clusterradius(app,event,app.handles.clusterradius+1)
        end

        % Button pushed function: plusphase_pushbutton
        function plusphase_pushbutton_Callback(app, event)
            app.handles.phase = app.handles.phase + 100;
            app.phase_edit.Value = app.handles.phase;
            app.phase_slider.Value = app.handles.phase;
            refreshPhase(app, event);
        end

        % Button pushed function: powerSpec_pushbutton
        function powerSpec_pushbutton_Callback(app, event)

            %             patterson = ift2(app.handles.hologram.masked);
            pattersonPropagated = ift2(app.handles.hologram.propagated);
            %             diffractionMask = app.handles.mask;

            %             radiusAutoCorr = 100;
            %             autoCorr = zeropadding(patterson(512-radiusAutoCorr:512+radiusAutoCorr,512-radiusAutoCorr:512+radiusAutoCorr),1024);
            %             FAutoCorr = ft2(autoCorr);

            crossCorrMask = zeros(1024);
            crossCorrMask(app.data.recon.roi(2):app.data.recon.roi(2)+app.data.recon.roi(4),app.data.recon.roi(1):app.data.recon.roi(1)+app.data.recon.roi(3)) = 1;
            crossCorr1 = pattersonPropagated.*crossCorrMask;
            %             crossCorr2 = conj(flipud(fliplr(crossCorr1))); %#ok<FLUDLR>
            FCrossCorr1 = ft2(crossCorr1);
            FCrossCorr1 = FCrossCorr1/mean(size(FCrossCorr1));
            %             FCrossCorr2 = ft2(crossCorr2);
            %             FCrossCorr12 = FCrossCorr1.*FCrossCorr2;

            %             diffractionIntensity1 = FAutoCorr/2 + sqrt(FAutoCorr.^2/4 - FCrossCorr12);
            %             diffractionIntensity2 = FAutoCorr/2 - sqrt(FAutoCorr.^2/4 - FCrossCorr12);
            %             diffractionIntensity1 = diffractionIntensity1.*diffractionMask;
            %             diffractionIntensity2 = diffractionIntensity2.*diffractionMask;
            %
            %             % figure(2);
            %             % subplot(131); imagesc(log10(abs(autoCorr)));  axis square; colormap imorgen; colorbar; crange(3);
            %             % subplot(132); imagesc(log10(abs(crossCorr1)));  axis square; colormap imorgen; colorbar; crange(3);
            %             % subplot(133); imagesc(log10(abs(crossCorr2)));  axis square; colormap imorgen; colorbar; crange(3);

            %             colrange = [0,4.2];
            %             figure(400);
            %             subplot(221); imagesc(log10(abs(FAutoCorr)), colrange); axis square; colormap imorgen; colorbar; title('FT of Autocorrelation');
            %             subplot(222); imagesc(log10(abs(FCrossCorr12)), colrange); axis square; colormap imorgen; colorbar; title('FT of cross term crossCorr');
            %             subplot(223); imagesc(log10(abs(diffractionIntensity1)), colrange); axis square; colormap imorgen; colorbar; title('Reconstructed Amplitudes Sample');
            %             subplot(224); imagesc(log10(abs(diffractionIntensity2)), colrange); axis square; colormap imorgen; colorbar; title('Reconstructed Amplitudes Reference');
            app.calc_rmean_range();
            [spectrumCC1, xCC1] = rmean(abs(FCrossCorr1).^2, app.handles.rmean.range, [], app.handles.rmean.bins);
            %             [spectrumCC2, xCC2] = rmean(abs(FCrossCorr2).^2, app.handles.rmean.range, [], app.handles.rmean.bins);
            %             [spectrumAC, xAC] = rmean(abs(FAutoCorr), app.handles.rmean.range, [], app.handles.rmean.bins);
            %             [spectrumCC12, xCC12] = rmean(abs(FCrossCorr12), app.handles.rmean.range, [], app.handles.rmean.bins);
            %             [spectrumDiffraction1, xD1] = rmean(abs(diffractionIntensity1), app.handles.rmean.range, [], app.handles.rmean.bins);
            %             [spectrumDiffraction2, xD2] = rmean(abs(diffractionIntensity2), app.handles.rmean.range, [], app.handles.rmean.bins);

            %             spectrumCC1 = spectrumCC1/max(spectrumCC1(:));
            %             spectrumCC2 = spectrumCC2/max(spectrumCC2(:));
            %             spectrumAC = spectrumAC/max(spectrumAC(:));
            %             spectrumCC12 = spectrumCC12/max(spectrumCC12(:));
            %             spectrumDiffraction1 = spectrumDiffraction1/max(spectrumDiffraction1(:));
            %             spectrumDiffraction2 = spectrumDiffraction2/max(spectrumDiffraction2(:));

            if app.decon_checkbox.Value
                hologram = abs(app.handles.hologram.deconvoluted);
            else
                hologram = abs(app.handles.hologram.propagated);
            end
            hologram = hologram/mean(size(hologram));
            app.calc_rmean_range();
            [spectrumHolo, xH] = rmean(hologram, app.handles.rmean.range, [], app.handles.rmean.bins);

            if ~isgraphics(app.handles.powerSpectrum.figure)
                app.handles.powerSpectrum.figure = figure('Name','power spectrum');
                app.handles.powerSpectrum.figure.Tag = 'holoShow.figures.powerSpectrum';
                app.handles.powerSpectrum.Axes = axes(app.handles.powerSpectrum.figure);
            end
            app.handles.powerSpectrum.Axes.NextPlot = 'replace';
            dn = app.handles.lambda*app.handles.detDistance/75e-6*1e9/2;
            dq = 2*pi/dn/2;
            semilogy(app.handles.powerSpectrum.Axes, xH*dq, spectrumHolo, 'DisplayName', 'hologram');
            app.handles.powerSpectrum.Axes.NextPlot = 'add';
            grid(app.handles.powerSpectrum.Axes, 'on');
            semilogy(app.handles.powerSpectrum.Axes, xCC1*dq, spectrumCC1, '--', 'DisplayName', 'cross correlation');
            %             semilogy(app.handles.powerSpectrum.Axes, xCC2, spectrumCC2, '--', 'DisplayName', 'twin cross correlation');
            %             %             semilogy(app.handles.powerSpectrum.Axes, xAC, spectrumAC, 'DisplayName', );
            %             %             semilogy(app.handles.powerSpectrum.Axes, xCC12, spectrumCC12, 'DisplayName', );
            %             semilogy(app.handles.powerSpectrum.Axes, xD1, spectrumDiffraction1, '*', 'DisplayName', 'reconstructed pattern 1');
            %             semilogy(app.handles.powerSpectrum.Axes, xD2, spectrumDiffraction2, '-.', 'DisplayName', 'reconstructed pattern 2');
            xtight(app.handles.powerSpectrum.Axes)
            ytight(app.handles.powerSpectrum.Axes)
            xlabel(app.handles.powerSpectrum.Axes, 'q [nm^{-1}]')
            title(app.handles.powerSpectrum.Axes, 'power spectrum');
            legend(app.handles.powerSpectrum.Axes)

            if app.decon_checkbox.Value
                app.data.hologram.spectrumDecon = spectrumHolo;
                app.data.recon.spectrumDecon = spectrumCC1;
            else
                app.data.hologram.spectrum = spectrumHolo;
                app.data.recon.spectrum = spectrumCC1;
            end

        end

        % Button pushed function: previous_pushbutton
        function previous_pushbutton_Callback(app, event)
            scrollHologram(app,event,-1)
        end

        % Button pushed function: reset_pushbutton
        function reset_pushbutton_Callback(app, event)

            %             figure(app.handles.reconstruction.figure)
            app.data.recon.roi(1) = 1;
            app.data.recon.roi(2) = 1;
            app.data.recon.roi(3) = size(app.handles.hologram.orig,2)-1;
            app.data.recon.roi(4) = size(app.handles.hologram.orig,1)-1;
            app.handles.reconAxes.XLim = [1, 1+app.data.recon.roi(3)];
            app.handles.reconAxes.YLim = [1, 1+app.data.recon.roi(4)];

            refreshImage(app, event)
            
            %             show_recon(app, event);

        end

        % Button pushed function: save_handles_pushbutton
        function save_handles_pushbutton_Callback(app, event)

            shotname = app.filenames_listbox.Value;
            %             shotlist=get(app.filenames_listbox, 'String');
            %             shotname=char(shotlist(get(app.filenames_listbox, 'Value'))) %#ok<NOPRT>
            % foldername=shotname(1:5);
            % anapath = fullfile(app.handles.sourcepath, 'analysis','good_shots');
            % if ~exist(anapath, 'dir')
            %     mkdir(anapath)
            % end
            % fileID=fopen(fullfile(anapath,[foldername,'.txt']),'a');
            % fprintf(fileID,'%s\n',shotname);
            % fclose(fileID);

            anapath = fullfile(app.handles.sourcepath, 'analysis','interesting');
            if ~exist(anapath, 'dir')
                mkdir(anapath)
            end

            % picpath = uigetdir
            % print(figure(1), fullfile(picpath, [shotname(1:end-4), '_scatt.png']), '-dpng');
            [filename, pathname] = uiputfile('*.mat','Save app.handles as',fullfile(anapath, [shotname(1:end-4), '_handles.mat']));
            handles = app.handles; %#ok<ADPROPLC>
            save(fullfile(pathname, filename), 'handles');
        end

        % Value changed function: scale_checkbox
        function scale_checkbox_Callback(app, event)

            axes(app.handles.reconAxes);
            if get(app.scale_checkbox, 'Value')
                caxis manual;
                app.handles.minScale = str2double(get(app.min_edit, 'Value'));
                app.handles.maxScale = str2double(get(app.max_edit, 'Value'));
                caxis([app.handles.minScale, app.handles.maxScale]);
            else
                caxis auto;
            end

        end

        % Value changed function: scattRatio_slider
        function scattRatio_slider_Callback(app, event)
            app.handles.scat_ratio = app.scattRatio_slider.Value;
            app.scattRatio_edit.Value = app.handles.scat_ratio;

            decon_checkbox_Callback(app, event)
        end

        % Button pushed function: select_pushbutton
        function select_pushbutton_Callback(app, event)

            select_hologram(app, event); % GRAB HOLOGRAM

        end

        % Button pushed function: shifts_pushbutton
        function shifts_pushbutton_Callback(app, event)

            [shift, slit, app.handles.phaseOffset] = find_shift(app.handles.hologram.masked, app.handles.phase, app.data.recon.roi);
            app.handles.add_shift = app.handles.add_shift + shift;
            app.handles.add_slit = app.handles.add_slit + slit;
            refresh_hologram(app, event);

        end

        % Value changed function: smoothMask_checkbox
        function smoothMask_checkbox_Callback(app, event)

            app.handles.smoothMask = app.smoothMask_checkbox.Value;
            select_hologram(app, event);

        end

        % Value changed function: wavelength_edit
        function wavelength_edit_Callback(app, event)

            app.handles.lambda = str2double(get(app.wavelength_edit, 'Value'))*1e-9;
            refresh_hologram(app, event);

        end

        % Button pushed function: wholeRun_pushbutton
        function wholeRun_pushbutton_Callback(app, event)
            fprintf('Looking for cross correlations and focusing them for current run ...\n')
            app.handles.holoSumOrig = app.handles.hologram.orig;
            app.handles.holoSumMasked = app.handles.hologram.masked; 
            app.handles.fileIndex = 1;
            while true 
                if app.handles.fileIndex > app.handles.nbr_images
                    fprintf('\n\trun #%03d finished\n\n', app.data.run)
                    break;
                end
                if app.handles.db.uitable.Data.LitPixel(app.handles.fileIndex)<30000 || app.handles.db.uitable.Data.MultiOld(app.handles.fileIndex)<5
                    app.handles.fileIndex = app.handles.fileIndex+1;
                    continue
                end

%                 if app.data.run == 437 && app.handles.fileIndex<573
%                     app.handles.fileIndex = 573;
%                 end
% 
%                 if app.data.run == 433
%                     if app.handles.fileIndex<867
%                         app.handles.fileIndex = 867;
%                     elseif app.handles.fileIndex>1049
%                         app.handles.fileIndex = app.handles.nbr_images;
%                     end
%                 end
%                 
%                 if app.data.run == 424
%                     if app.handles.fileIndex>60
%                         app.handles.fileIndex = app.handles.nbr_images;
%                     end
%                 end

                
                app.filenames_listbox.Value = app.handles.fileIndex;
                select_hologram(app, event);
                %     app.handles.centroids = findCrossCorrs(app.handles.recon, 'show_img', true, 'min_dist', 100, 'int_thresh', 5, 'r_ignored', 75);
                %     focusCC_pushbutton_Callback(app, event);
                if get(app.abort_pushbutton, 'UserData'), break; end
                
                focusCC_pushbutton_Callback(app, event);

%                 app.handles.holoSumOrig = app.handles.holoSumOrig + app.handles.hologram.orig;
%                 app.handles.holoSumMasked = app.handles.holoSumMasked + app.handles.hologram.masked;
                %                 figure(20001);
                %                 subplot(221); imagesc(abs(app.handles.holoSumOrig));
                %                 subplot(222); imagesc(abs(app.handles.holoSumOrig));
                %                 set(gca, 'ColorScale', 'log', 'CLim', CLim(2)*[1e-4, 1])
                %                 subplot(223); imagesc(abs(app.handles.holoSumMasked));
                %                 subplot(224); imagesc(abs(app.handles.holoSumMasked));
                %                 set(gca, 'ColorScale', 'log', 'CLim', CLim(2)*[1e-4, 1])
                %                 drawnow
                app.handles.fileIndex = app.handles.fileIndex+1;
            end
            
            set(app.abort_pushbutton, 'UserData', false)
%             holoSum.figure = app.get_figure('holo_sum');
%             clf(holoSum.figure);
%             holoSum.ax(1) = subplot(121); holoSum.img(1) = imagesc(abs(app.handles.holoSumOrig));
%             holoSum.ax(2) = subplot(122); holoSum.img(2) = imagesc(abs(app.handles.holoSumMasked));
%             holoSum.ax(1).ColorScale = 'log'; holoSum.ax(1).CLim(1) = holoSum.ax(1).CLim(2)*1e-4;
%             holoSum.ax(2).ColorScale = 'log'; holoSum.ax(2).CLim(1) = holoSum.ax(2).CLim(2)*1e-4;
%             app.handles.holoSum = holoSum;
            drawnow
        end

        % Value changing function: phase_slider
        function phase_sliderValueChanging(app, event)
            app.handles.phase = round(event.Value);
            app.phase_edit.Value = app.handles.phase;
            refreshPhase(app, event)
        end

        % Button pushed function: calcMultiscore_pushbutton
        function calcMultiscore_pushbuttonButtonPushed(app, event)
            app.handles.fileIndex = 0;
            while app.handles.fileIndex <= app.handles.nbr_images
                app.handles.fileIndex = app.handles.fileIndex+1;
                app.filenames_listbox.Value = app.handles.fileIndex;
                select_hologram(app, event);
                calcMulti(app,event);
                if get(app.abort_pushbutton, 'UserData')
                    set(app.abort_pushbutton, 'UserData', false)
                    break
                end
            end
        end

        % Value changed function: scattRatio_edit
        function scattRatio_editValueChanged(app, event)
            app.handles.scat_ratio = str2double(strrep(app.scattRatio_edit.Value, ',', '.'));
            app.scattRatio_slider.Value = app.handles.scat_ratio;

            decon_checkbox_Callback(app, event)
        end

        % Value changed function: phase_edit
        function phase_editValueChanged(app, event)
            app.handles.phase = app.phase_edit.Value;
            app.phase_slider.Value = app.handles.phase;
            refreshImage(app, event);
        end

        % Value changed function: clusterradius_edit
        function clusterradius_editValueChanged(app, event)
            update_clusterradius(app,event,app.clusterradius_edit.Value);
        end

        % Value changed function: range_edit
        function range_editValueChanged(app, event)
            newRange = app.range_edit.Value;
            app.phase_slider.Limits = [-1,1]*newRange;
            app.phase_slider.MajorTicks = [-1,-.5,0,.5,1]*newRange;
            app.phase_slider.MajorTickLabels = cellfun(@(x) sprintf('%d',x), num2cell(app.phase_slider.MajorTicks), 'UniformOutput',false);
        end

        % Button pushed function: minus2_radius_pushbutton
        function minus2_radius_pushbuttonButtonPushed(app, event)
            update_clusterradius(app,event,app.handles.clusterradius-2)
        end

        % Button pushed function: minus5_radius_pushbutton
        function minus5_radius_pushbuttonButtonPushed(app, event)
            update_clusterradius(app,event,app.handles.clusterradius-5)
        end

        % Button pushed function: minus10_radius_pushbutton
        function minus10_radius_pushbuttonButtonPushed(app, event)
            update_clusterradius(app,event,app.handles.clusterradius-10)
        end

        % Button pushed function: plus2_radius_pushbutton
        function plus2_radius_pushbuttonButtonPushed(app, event)
            update_clusterradius(app,event,app.handles.clusterradius+2)
        end

        % Button pushed function: plus5_radius_pushbutton
        function plus5_radius_pushbuttonButtonPushed(app, event)
            update_clusterradius(app,event,app.handles.clusterradius+5)
        end

        % Button pushed function: plus10_radius_pushbutton
        function plus10_radius_pushbuttonButtonPushed(app, event)
            update_clusterradius(app,event,app.handles.clusterradius+10)
        end

        % Window scroll wheel function: holoShow
        function holoShowWindowScrollWheel(app, event)
%             verticalScrollAmount = event.VerticalScrollAmount;
            verticalScrollCount = event.VerticalScrollCount;
            app.handles.phase = app.handles.phase + 100*verticalScrollCount;
            app.phase_edit.Value = app.handles.phase;
            app.phase_slider.Value = app.handles.phase;
            refreshPhase(app, event);
        end

        % Value changed function: clusterradius_slider
        function clusterradius_sliderValueChanged(app, event)
            update_clusterradius(app,event,app.clusterradius_slider.Value);
        end

        % Value changing function: clusterradius_slider
        function clusterradius_sliderValueChanging(app, event)
            update_clusterradius(app,event,app.clusterradius_slider.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.holoShow = uifigure('Visible', 'off');
            app.holoShow.Name = 'HoloShow v4.0';
            app.holoShow.CloseRequestFcn = createCallbackFcn(app, @holoShow_CloseRequestFcn, true);
            app.holoShow.WindowScrollWheelFcn = createCallbackFcn(app, @holoShowWindowScrollWheel, true);
            app.holoShow.KeyPressFcn = createCallbackFcn(app, @holoShow_KeyPressFcn, true);
            app.holoShow.Tag = 'holoShow.figures.main';
            app.uipanel1 = uipanel(app.holoShow);
            app.uipanel1.Title = 'select';
            app.uipanel1.Tag = 'uipanel1';
            app.load_pushbutton = uibutton(app.uipanel1, 'push');
            app.load_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @load_pushbutton_Callback, true);
            app.load_pushbutton.Tag = 'load_pushbutton';
            app.load_pushbutton.Text = '(l)oad files';
            app.filenames_listbox = uilistbox(app.uipanel1);
            app.filenames_listbox.Items = {'files'};
            app.filenames_listbox.ValueChangedFcn = createCallbackFcn(app, @filenames_listbox_Callback, true);
            app.filenames_listbox.Tag = 'filenames_listbox';
            app.filenames_listbox.Value = 'files';
            app.select_pushbutton = uibutton(app.uipanel1, 'push');
            app.select_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @select_pushbutton_Callback, true);
            app.select_pushbutton.Tag = 'select_pushbutton';
            app.select_pushbutton.Text = 'show selected hologram';
            app.next_pushbutton = uibutton(app.uipanel1, 'push');
            app.next_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @next_pushbutton_Callback, true);
            app.next_pushbutton.Tag = 'next_pushbutton';
            app.next_pushbutton.Text = 'next shot';
            app.previous_pushbutton = uibutton(app.uipanel1, 'push');
            app.previous_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @previous_pushbutton_Callback, true);
            app.previous_pushbutton.Tag = 'previous_pushbutton';
            app.previous_pushbutton.Text = 'previous shot';
            app.applyHologramCorrection_checkbox = uicheckbox(app.uipanel1);
            app.applyHologramCorrection_checkbox.ValueChangedFcn = createCallbackFcn(app, @applyHologramCorrection_checkbox_Callback, true);
            app.applyHologramCorrection_checkbox.Tag = 'applyHologramCorrection_checkbox';
            app.applyHologramCorrection_checkbox.Text = 'image correction';
            app.applyHologramCorrection_checkbox.Value = true;
            app.smoothMask_checkbox = uicheckbox(app.uipanel1);
            app.smoothMask_checkbox.ValueChangedFcn = createCallbackFcn(app, @smoothMask_checkbox_Callback, true);
            app.smoothMask_checkbox.Tag = 'smoothMask_checkbox';
            app.smoothMask_checkbox.Text = 'mask smooting';
            app.smoothMask_checkbox.Value = true;
            app.config_dropdown = uidropdown(app.uipanel1);
            app.config_dropdown.Items = {'FLASH2017', 'LCLS2016', 'LCLS2014'};
            app.config_dropdown.ValueChangedFcn = createCallbackFcn(app, @config_dropdown_Callback, true);
            app.config_dropdown.Tag = 'config_popupmenu';
            app.config_dropdown.Value = 'LCLS2014';
            app.experiment_text = uilabel(app.uipanel1);
            app.experiment_text.Tag = 'experiment_text';
            app.experiment_text.Text = 'experiment';
            app.phase_slider = uislider(app.holoShow);
            app.phase_slider.Limits = [-30000 30000];
            app.phase_slider.MajorTicks = [-30000 -15000 0 15000 30000];
            app.phase_slider.MajorTickLabels = {'-30000', '-15000', '0', '15000', '30000'};
            app.phase_slider.ValueChangedFcn = createCallbackFcn(app, @phase_slider_Callback, true);
            app.phase_slider.ValueChangingFcn = createCallbackFcn(app, @phase_sliderValueChanging, true);
            app.phase_slider.MinorTicks = [];
            app.phase_slider.Tag = 'phase_slider';
            app.phase_text = uilabel(app.holoShow);
            app.phase_text.Tag = 'text2';
            app.phase_text.Text = 'phase';
            app.find_phase_pushbutton = uibutton(app.holoShow, 'push');
            app.find_phase_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @find_phase_pushbutton_Callback, true);
            app.find_phase_pushbutton.Tag = 'find_phase_pushbutton';
            app.find_phase_pushbutton.Text = '(f)ind phase';
            app.SNR_pushbutton = uibutton(app.holoShow, 'push');
            app.SNR_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @SNR_pushbutton_Callback, true);
            app.SNR_pushbutton.Tag = 'SNR_pushbutton';
            app.SNR_pushbutton.Text = '(S)NR';
            app.noise_pushbutton = uibutton(app.holoShow, 'push');
            app.noise_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @noise_pushbutton_Callback, true);
            app.noise_pushbutton.Tag = 'noise_pushbutton';
            app.noise_pushbutton.Text = 'choose (n)oise';
            app.powerSpec_pushbutton = uibutton(app.holoShow, 'push');
            app.powerSpec_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @powerSpec_pushbutton_Callback, true);
            app.powerSpec_pushbutton.Tag = 'powerSpec_pushbutton';
            app.powerSpec_pushbutton.Text = '(p)ower spectrum';
            app.goodShot_pushbutton = uibutton(app.holoShow, 'push');
            app.goodShot_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @goodShot_pushbutton_Callback, true);
            app.goodShot_pushbutton.Tag = 'goodShot_pushbutton';
            app.goodShot_pushbutton.Text = '(g)ood shot!';
            app.knife_edge_pushbutton = uibutton(app.holoShow, 'push');
            app.knife_edge_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @knife_edge_pushbutton_Callback, true);
            app.knife_edge_pushbutton.Tag = 'knife_edge_pushbutton';
            app.knife_edge_pushbutton.Text = '(k)nife-edge';
            app.decon_checkbox = uicheckbox(app.holoShow);
            app.decon_checkbox.ValueChangedFcn = createCallbackFcn(app, @decon_checkbox_Callback, true);
            app.decon_checkbox.Tag = 'decon_checkbox';
            app.decon_checkbox.Text = '(d)econvolution';
            app.uipanel3 = uipanel(app.holoShow);
            app.uipanel3.Title = 'hologram corrections';
            app.uipanel3.Tag = 'uipanel3';
            app.lowpass_edit = uieditfield(app.uipanel3, 'text');
            app.lowpass_edit.ValueChangedFcn = createCallbackFcn(app, @lowpass_edit_Callback, true);
            app.lowpass_edit.Tag = 'lowpass_edit';
            app.lowpass_edit.Value = '332';
            app.highpass_edit = uieditfield(app.uipanel3, 'text');
            app.highpass_edit.ValueChangedFcn = createCallbackFcn(app, @highpass_edit_Callback, true);
            app.highpass_edit.Tag = 'highpass_edit';
            app.highpass_edit.Value = '60';
            app.highpass_checkbox = uicheckbox(app.uipanel3);
            app.highpass_checkbox.ValueChangedFcn = createCallbackFcn(app, @highpass_checkbox_Callback, true);
            app.highpass_checkbox.Tag = 'highpass_checkbox';
            app.highpass_checkbox.Text = 'high pass filter';
            app.lowpass_checkbox = uicheckbox(app.uipanel3);
            app.lowpass_checkbox.ValueChangedFcn = createCallbackFcn(app, @lowpass_checkbox_Callback, true);
            app.lowpass_checkbox.Tag = 'lowpass_checkbox';
            app.lowpass_checkbox.Text = 'low pass filter';
            app.cm_checkbox = uicheckbox(app.uipanel3);
            app.cm_checkbox.ValueChangedFcn = createCallbackFcn(app, @cm_checkbox_Callback, true);
            app.cm_checkbox.Tag = 'cm_checkbox';
            app.cm_checkbox.Text = 'common mode correction';
            app.load_mask_checkbox = uicheckbox(app.uipanel3);
            app.load_mask_checkbox.ValueChangedFcn = createCallbackFcn(app, @load_mask_checkbox_Callback, true);
            app.load_mask_checkbox.Tag = 'load_mask_checkbox';
            app.load_mask_checkbox.Text = 'load custom mask';
            app.wavelength_edit = uieditfield(app.uipanel3, 'text');
            app.wavelength_edit.ValueChangedFcn = createCallbackFcn(app, @wavelength_edit_Callback, true);
            app.wavelength_edit.Tag = 'wavelength_edit';
            app.wavelength_edit.Value = '1.053';
            app.lambda_text = uilabel(app.uipanel3);
            app.lambda_text.Tag = 'text8';
            app.lambda_text.Text = 'lambda = ';
            app.nmLambda_text = uilabel(app.uipanel3);
            app.nmLambda_text.Tag = 'text9';
            app.nmLambda_text.Text = 'nm';
            app.detDist_edit = uieditfield(app.uipanel3, 'text');
            app.detDist_edit.ValueChangedFcn = createCallbackFcn(app, @detDist_edit_Callback, true);
            app.detDist_edit.Tag = 'detDist_edit';
            app.detDist_edit.Value = '0.735';
            app.detDistance_text = uilabel(app.uipanel3);
            app.detDistance_text.Tag = 'text10';
            app.detDistance_text.Text = 'detector dist. = ';
            app.mDetDistance_text = uilabel(app.uipanel3);
            app.mDetDistance_text.Tag = 'text11';
            app.mDetDistance_text.Text = 'm';
            app.intensity_filter_edit = uieditfield(app.uipanel3, 'text');
            app.intensity_filter_edit.ValueChangedFcn = createCallbackFcn(app, @intensity_filter_edit_Callback, true);
            app.intensity_filter_edit.Tag = 'intensity_filter_edit';
            app.intensity_filter_edit.Value = '10000';
            app.intensity_filter_checkbox = uicheckbox(app.uipanel3);
            app.intensity_filter_checkbox.ValueChangedFcn = createCallbackFcn(app, @intensity_filter_checkbox_Callback, true);
            app.intensity_filter_checkbox.Tag = 'intensity_filter_checkbox';
            app.intensity_filter_checkbox.Text = 'intensity filter';
            app.pxLabel_2 = uilabel(app.uipanel3);
            app.pxLabel_2.Text = 'px';
            app.pxLabel_3 = uilabel(app.uipanel3);
            app.pxLabel_3.Text = 'px';
            app.pxLabel_4 = uilabel(app.uipanel3);
            app.pxLabel_4.Text = 'px';
            app.uipanel4 = uipanel(app.holoShow);
            app.uipanel4.Title = 'analysis';
            app.uipanel4.Tag = 'uipanel4';
            app.findCC_pushbutton = uibutton(app.uipanel4, 'push');
            app.findCC_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @findCC_pushbutton_Callback, true);
            app.findCC_pushbutton.Tag = 'findCC_pushbutton';
            app.findCC_pushbutton.Text = 'find CCs';
            app.focusCC_pushbutton = uibutton(app.uipanel4, 'push');
            app.focusCC_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @focusCC_pushbutton_Callback, true);
            app.focusCC_pushbutton.Tag = 'focusCC_pushbutton';
            app.focusCC_pushbutton.Text = 'focus CCs';
            app.wholeRun_pushbutton = uibutton(app.uipanel4, 'push');
            app.wholeRun_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @wholeRun_pushbutton_Callback, true);
            app.wholeRun_pushbutton.Tag = 'wholeRun_pushbutton';
            app.wholeRun_pushbutton.Text = 'whole run!';
            app.shifts_pushbutton = uibutton(app.uipanel4, 'push');
            app.shifts_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @shifts_pushbutton_Callback, true);
            app.shifts_pushbutton.Tag = 'shifts_pushbutton';
            app.shifts_pushbutton.Text = 'find s(h)ifts';
            app.center_pushbutton = uibutton(app.uipanel4, 'push');
            app.center_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @center_pushbutton_Callback, true);
            app.center_pushbutton.Tag = 'center_pushbutton';
            app.center_pushbutton.Text = 'find c(e)nter';
            app.find_decon_pushbutton = uibutton(app.uipanel4, 'push');
            app.find_decon_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @find_decon_pushbutton_Callback, true);
            app.find_decon_pushbutton.Tag = 'find_decon_pushbutton';
            app.find_decon_pushbutton.Enable = 'off';
            app.find_decon_pushbutton.Text = 'find decon';
            app.choose_runs_pushbutton = uibutton(app.uipanel4, 'push');
            app.choose_runs_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @choose_runs_pushbutton_Callback, true);
            app.choose_runs_pushbutton.Tag = 'choose_runs_pushbutton';
            app.choose_runs_pushbutton.Text = 'choose runs';
            app.parameter_window_pushbutton = uibutton(app.uipanel4, 'push');
            app.parameter_window_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @parameter_window_pushbutton_Callback, true);
            app.parameter_window_pushbutton.Tag = 'parameter_window_pushbutton';
            app.parameter_window_pushbutton.Enable = 'off';
            app.parameter_window_pushbutton.Text = 'parameter';
            app.calcMultiscore_pushbutton = uibutton(app.uipanel4, 'push');
            app.calcMultiscore_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @calcMultiscore_pushbuttonButtonPushed, true);
            app.calcMultiscore_pushbutton.Text = 'calc multis. for run';
            app.uipanel2 = uipanel(app.holoShow);
            app.uipanel2.Title = 'reconstruction image control';
            app.uipanel2.Tag = 'uipanel2';
            app.part_buttongroup = uibuttongroup(app.uipanel2);
            app.part_buttongroup.SelectionChangedFcn = createCallbackFcn(app, @part_buttongroup_SelectionChangedFcn, true);
            app.part_buttongroup.Title = 'plot part';
            app.part_buttongroup.Tag = 'part_buttongroup';
            app.angle_r = uiradiobutton(app.part_buttongroup);
            app.angle_r.Tag = 'angle_r';
            app.angle_r.Text = 'angle';
            app.abs_r = uiradiobutton(app.part_buttongroup);
            app.abs_r.Tag = 'abs_r';
            app.abs_r.Text = 'abs';
            app.imag_r = uiradiobutton(app.part_buttongroup);
            app.imag_r.Tag = 'imag_r';
            app.imag_r.Text = 'imag';
            app.real_r = uiradiobutton(app.part_buttongroup);
            app.real_r.Tag = 'real_r';
            app.real_r.Text = 'real';
            app.real_r.Value = true;
            app.min_edit = uieditfield(app.uipanel2, 'text');
            app.min_edit.ValueChangedFcn = createCallbackFcn(app, @min_edit_Callback, true);
            app.min_edit.Tag = 'min_edit';
            app.min_edit.Value = '-2';
            app.max_edit = uieditfield(app.uipanel2, 'text');
            app.max_edit.ValueChangedFcn = createCallbackFcn(app, @max_edit_Callback, true);
            app.max_edit.Tag = 'max_edit';
            app.max_edit.Value = '2';
            app.scale_checkbox = uicheckbox(app.uipanel2);
            app.scale_checkbox.ValueChangedFcn = createCallbackFcn(app, @scale_checkbox_Callback, true);
            app.scale_checkbox.Tag = 'scale_checkbox';
            app.scale_checkbox.Text = 'fixed range';
            app.scale_checkbox.Value = true;
            app.log_checkbox = uicheckbox(app.uipanel2);
            app.log_checkbox.ValueChangedFcn = createCallbackFcn(app, @log_checkbox_Callback, true);
            app.log_checkbox.Tag = 'log_checkbox';
            app.log_checkbox.Text = 'log scale';
            app.fromRange_text = uilabel(app.uipanel2);
            app.fromRange_text.Tag = 'text4';
            app.fromRange_text.Text = 'from';
            app.toRange_text = uilabel(app.uipanel2);
            app.toRange_text.Tag = 'text5';
            app.toRange_text.Text = 'to';
            app.colormap_buttongroup = uibuttongroup(app.uipanel2);
            app.colormap_buttongroup.SelectionChangedFcn = createCallbackFcn(app, @colormap_buttongroup_SelectionChangedFcn, true);
            app.colormap_buttongroup.Title = 'colormap';
            app.colormap_buttongroup.Tag = 'colormap_buttongroup';
            app.hsv_radiobutton = uiradiobutton(app.colormap_buttongroup);
            app.hsv_radiobutton.Tag = 'hsv_radiobutton';
            app.hsv_radiobutton.Text = 'hsv';
            app.ihesperia_radiobutton = uiradiobutton(app.colormap_buttongroup);
            app.ihesperia_radiobutton.Tag = 'fire_radiobutton';
            app.ihesperia_radiobutton.Text = 'ihesp';
            app.gray_radiobutton = uiradiobutton(app.colormap_buttongroup);
            app.gray_radiobutton.Tag = 'gray_radiobutton';
            app.gray_radiobutton.Text = 'gray';
            app.gray_radiobutton.Value = true;
            app.r2b_radiobutton = uiradiobutton(app.colormap_buttongroup);
            app.r2b_radiobutton.Tag = 'jet_radiobutton';
            app.r2b_radiobutton.Text = 'r2b';
            app.crop_edit = uieditfield(app.uipanel2, 'text');
            app.crop_edit.ValueChangedFcn = createCallbackFcn(app, @crop_edit_Callback, true);
            app.crop_edit.Tag = 'crop_edit';
            app.crop_edit.Value = '2';
            app.crop_checkbox = uicheckbox(app.uipanel2);
            app.crop_checkbox.ValueChangedFcn = createCallbackFcn(app, @crop_checkbox_Callback, true);
            app.crop_checkbox.Tag = 'crop_checkbox';
            app.crop_checkbox.Text = 'crop image';
            app.cropTimes_text = uilabel(app.uipanel2);
            app.cropTimes_text.Tag = 'text13';
            app.cropTimes_text.Text = 'times';
            app.cut_center_edit = uieditfield(app.uipanel2, 'text');
            app.cut_center_edit.ValueChangedFcn = createCallbackFcn(app, @cut_center_edit_Callback, true);
            app.cut_center_edit.Tag = 'cut_center_edit';
            app.cut_center_edit.Value = '75';
            app.cut_center_checkbox = uicheckbox(app.uipanel2);
            app.cut_center_checkbox.ValueChangedFcn = createCallbackFcn(app, @cut_center_checkbox_Callback, true);
            app.cut_center_checkbox.Tag = 'cut_center_checkbox';
            app.cut_center_checkbox.Text = 'cut center out';
            app.pxLabel = uilabel(app.uipanel2);
            app.pxLabel.Text = 'px';
            app.chooseCC_pushbutton = uibutton(app.uipanel2, 'push');
            app.chooseCC_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @chooseCC_pushbutton_Callback, true);
            app.chooseCC_pushbutton.Tag = 'chooseCC_pushbutton';
            app.chooseCC_pushbutton.Text = '(c)hoose ROI';
            app.reset_pushbutton = uibutton(app.uipanel2, 'push');
            app.reset_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @reset_pushbutton_Callback, true);
            app.reset_pushbutton.Tag = 'reset_pushbutton';
            app.reset_pushbutton.Text = '(r)eset ROI';
            app.squareROI_checkbox = uicheckbox(app.uipanel2);
            app.squareROI_checkbox.Tag = 'squareROI_checkbox';
            app.squareROI_checkbox.Text = 'square ROI';
            app.squareROI_checkbox.Value = true;
            app.autofocus_popupmenu = uidropdown(app.holoShow);
            app.autofocus_popupmenu.Items = {'variance', 'gradient', 'laplacian', 'spectral', 'all'};
            app.autofocus_popupmenu.ValueChangedFcn = createCallbackFcn(app, @autofocus_popupmenu_Callback, true);
            app.autofocus_popupmenu.Tag = 'autofocus_popupmenu';
            app.autofocus_popupmenu.Value = 'variance';
            app.autofocusMethod_text = uilabel(app.holoShow);
            app.autofocusMethod_text.Tag = 'text12';
            app.autofocusMethod_text.Text = 'autofocus method:';
            app.save_handles_pushbutton = uibutton(app.holoShow, 'push');
            app.save_handles_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @save_handles_pushbutton_Callback, true);
            app.save_handles_pushbutton.Tag = 'save_handles_pushbutton';
            app.save_handles_pushbutton.Enable = 'off';
            app.save_handles_pushbutton.Text = 'save handles';
            app.abort_pushbutton = uibutton(app.holoShow, 'push');
            app.abort_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @abort_pushbutton_Callback, true);
            app.abort_pushbutton.Tag = 'abort_pushbutton';
            app.abort_pushbutton.Text = '(esc) abort';
            app.save_checkbox = uicheckbox(app.holoShow);
            app.save_checkbox.Tag = 'save_checkbox';
            app.save_checkbox.Text = 'save';
            app.plusphase_pushbutton = uibutton(app.holoShow, 'push');
            app.plusphase_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @plusphase_pushbutton_Callback, true);
            app.plusphase_pushbutton.Tag = 'plusphase_pushbutton';
            app.plusphase_pushbutton.Text = '+';
            app.minusphase_pushbutton = uibutton(app.holoShow, 'push');
            app.minusphase_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @minusphase_pushbutton_Callback, true);
            app.minusphase_pushbutton.Tag = 'minusphase_pushbutton';
            app.minusphase_pushbutton.Text = '-';
            app.plus1_radius_pushbutton = uibutton(app.holoShow, 'push');
            app.plus1_radius_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @plus1_radius_pushbutton_Callback, true);
            app.plus1_radius_pushbutton.Tag = 'plus_radius_pushbutton';
            app.plus1_radius_pushbutton.Text = '+1';
            app.minus1_radius_pushbutton = uibutton(app.holoShow, 'push');
            app.minus1_radius_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @minus1_radius_pushbutton_Callback, true);
            app.minus1_radius_pushbutton.Tag = 'minus_radius_pushbutton';
            app.minus1_radius_pushbutton.Text = '-1';
            app.command_edit = uieditfield(app.holoShow, 'text');
            app.command_edit.ValueChangedFcn = createCallbackFcn(app, @command_edit_Callback, true);
            app.command_edit.Tag = 'command_edit';
            app.command_edit.Tooltip = {'command line execution'};
            app.command_text = uilabel(app.holoShow);
            app.command_text.Tag = 'text15';
            app.command_text.Text = 'command';
            app.scattRatio_slider = uislider(app.holoShow);
            app.scattRatio_slider.Limits = [-3 3];
            app.scattRatio_slider.MajorTicks = [];
            app.scattRatio_slider.ValueChangedFcn = createCallbackFcn(app, @scattRatio_slider_Callback, true);
            app.scattRatio_slider.MinorTicks = [];
            app.scattRatio_slider.Tag = 'scattRatio_slider';
            app.scattRatio_slider.Value = -3;
            app.nmPhase_text = uilabel(app.holoShow);
            app.nmPhase_text.Tag = 'text7';
            app.nmPhase_text.Text = 'nm';
            app.nm3_text = uilabel(app.holoShow);
            app.nm3_text.Tag = 'text7';
            app.nm3_text.Text = 'nm';
            app.logscattratioEditFieldLabel = uilabel(app.holoShow);
            app.logscattratioEditFieldLabel.Text = 'log scatt ratio';
            app.scattRatio_edit = uieditfield(app.holoShow, 'numeric');
            app.scattRatio_edit.ValueChangedFcn = createCallbackFcn(app, @scattRatio_editValueChanged, true);
            app.scattRatio_edit.Value = -2;
            app.radiusnmEditFieldLabel = uilabel(app.holoShow);
            app.radiusnmEditFieldLabel.Text = 'radius (nm)';
            app.clusterradius_edit = uieditfield(app.holoShow, 'numeric');
            app.clusterradius_edit.ValueDisplayFormat = '%11.1f';
            app.clusterradius_edit.ValueChangedFcn = createCallbackFcn(app, @clusterradius_editValueChanged, true);
            app.clusterradius_edit.Value = 40;
            app.minus2_radius_pushbutton = uibutton(app.holoShow, 'push');
            app.minus2_radius_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @minus2_radius_pushbuttonButtonPushed, true);
            app.minus2_radius_pushbutton.Tag = 'minus_radius_pushbutton';
            app.minus2_radius_pushbutton.Text = '-2';
            app.minus5_radius_pushbutton = uibutton(app.holoShow, 'push');
            app.minus5_radius_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @minus5_radius_pushbuttonButtonPushed, true);
            app.minus5_radius_pushbutton.Tag = 'minus_radius_pushbutton';
            app.minus5_radius_pushbutton.Text = '-5';
            app.minus10_radius_pushbutton = uibutton(app.holoShow, 'push');
            app.minus10_radius_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @minus10_radius_pushbuttonButtonPushed, true);
            app.minus10_radius_pushbutton.Tag = 'minus_radius_pushbutton';
            app.minus10_radius_pushbutton.Text = '-10';
            app.plus2_radius_pushbutton = uibutton(app.holoShow, 'push');
            app.plus2_radius_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @plus2_radius_pushbuttonButtonPushed, true);
            app.plus2_radius_pushbutton.Tag = 'plus_radius_pushbutton';
            app.plus2_radius_pushbutton.Text = '+2';
            app.plus5_radius_pushbutton = uibutton(app.holoShow, 'push');
            app.plus5_radius_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @plus5_radius_pushbuttonButtonPushed, true);
            app.plus5_radius_pushbutton.Tag = 'plus_radius_pushbutton';
            app.plus5_radius_pushbutton.Text = '+5';
            app.plus10_radius_pushbutton = uibutton(app.holoShow, 'push');
            app.plus10_radius_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @plus10_radius_pushbuttonButtonPushed, true);
            app.plus10_radius_pushbutton.Tag = 'plus_radius_pushbutton';
            app.plus10_radius_pushbutton.Text = '+10';
            app.clusterradius_slider = uislider(app.holoShow);
            app.clusterradius_slider.Limits = [0 200];
            app.clusterradius_slider.MajorTicks = [0 20 40 60 80 100 120 140 160 180 200];
            app.clusterradius_slider.MajorTickLabels = {'0', '20', '40', '60', '80', '100', '120', '140', '160', '180', '200'};
            app.clusterradius_slider.ValueChangedFcn = createCallbackFcn(app, @clusterradius_sliderValueChanged, true);
            app.clusterradius_slider.ValueChangingFcn = createCallbackFcn(app, @clusterradius_sliderValueChanging, true);
            app.clusterradius_slider.MinorTicks = [];
            app.clusterradius_slider.Tag = 'phase_slider';
            app.clusterradius_slider.Value = 40;
            app.phase_edit = uieditfield(app.holoShow, 'numeric');
            app.phase_edit.ValueChangedFcn = createCallbackFcn(app, @phase_editValueChanged, true);
            app.scanrangeEditFieldLabel = uilabel(app.holoShow);
            app.scanrangeEditFieldLabel.Text = 'scan range:';
            app.range_edit = uieditfield(app.holoShow, 'numeric');
            app.range_edit.ValueDisplayFormat = '%.0f';
            app.range_edit.ValueChangedFcn = createCallbackFcn(app, @range_editValueChanged, true);
            app.range_edit.Value = 30000;
            % Show the figure after all components are created
            app.holoShow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HoloShow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.holoShow)

            % Execute the startup function
            runStartupFcn(app, @(app)holoShow_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.holoShow)
        end
    end
end